classdef ATM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ATMUIFigure                matlab.ui.Figure
        InvalidImage               matlab.ui.control.Image
        CardImage                  matlab.ui.control.Image
        CNYImage                   matlab.ui.control.Image
        CashInOutImage             matlab.ui.control.Image
        InsertImage                matlab.ui.control.Image
        WithdrawLabel              matlab.ui.control.TextArea
        DepositLabel               matlab.ui.control.TextArea
        DepositAmountyuanLabel     matlab.ui.control.Label
        DepositAmountEditField     matlab.ui.control.NumericEditField
        DepositConfirmButton       matlab.ui.control.Button
        WithdrawAmountyuanLabel    matlab.ui.control.Label
        WithdrawAmountEditField    matlab.ui.control.NumericEditField
        WithdrawConfirmButton      matlab.ui.control.Button
        DepositButton              matlab.ui.control.Button
        WithdrawButton             matlab.ui.control.Button
        InsertCardLabel            matlab.ui.control.Label
        InsertCardButton           matlab.ui.control.Button
        ChangePINLabel             matlab.ui.control.TextArea
        TransferLabel              matlab.ui.control.TextArea
        EnterAccountLabel          matlab.ui.control.Label
        EnterAccountTextArea       matlab.ui.control.TextArea
        ChangePINConfirmButton     matlab.ui.control.Button
        RepeatNewPINEditField      matlab.ui.control.NumericEditField
        RepeatNewPINLabel          matlab.ui.control.Label
        NewPINEditField            matlab.ui.control.NumericEditField
        NewPINLabel                matlab.ui.control.Label
        TransferConfirmButton      matlab.ui.control.Button
        TransferAmountyuanLabel    matlab.ui.control.Label
        TransferAmountEditField    matlab.ui.control.NumericEditField
        AccounttransferredtoLabel  matlab.ui.control.Label
        TransferEditField          matlab.ui.control.NumericEditField
        CheckBalanceLabel          matlab.ui.control.Label
        BalanceyuanLabel           matlab.ui.control.Label
        BalanceEditField           matlab.ui.control.NumericEditField
        TransactionLabel           matlab.ui.control.Label
        LogOutButton               matlab.ui.control.Button
        ChangePINButton            matlab.ui.control.Button
        QueryButton                matlab.ui.control.Button
        TransferButton             matlab.ui.control.Button
        LogInButton                matlab.ui.control.Button
        PINLabel                   matlab.ui.control.Label
        AccountLabel               matlab.ui.control.Label
        ReturnButton               matlab.ui.control.Button
        AccountEditField           matlab.ui.control.NumericEditField
        PINEditField               matlab.ui.control.NumericEditField
        WelcomeLabel               matlab.ui.control.Label
        ATMLabel                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        AccountDatabase;
        current_index = 0;
    end
    
    methods (Access = public)
        function press(app, key)                    % For testing only
            if app.InsertCardButton.Visible == 1
                if key == 'n'
                    app.CardImage.Visible = 1;
                elseif key == 'c'
                    app.CardImage.Visible = 0;
                end
            elseif app.DepositConfirmButton.Visible == 1
                if key == 'm'
                    app.CNYImage.Visible = 1;
                    app.InvalidImage.Visible = 0;
                elseif key == 'n'
                    app.InvalidImage.Visible = 1;
                    app.CNYImage.Visible = 0;
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: InsertCardButton
        function InsertCardButtonPushed(app, event)
            if app.CardImage.Visible == 1
                app.InsertCardLabel.Text = 'Your card is invalid! Please try another!';
                return;
            end
            app.InsertCardLabel.Text = 'Please insert your shanghaitech card first.';
            app.InsertImage.Visible = 0;
            app.InsertCardButton.Visible = 0;
            app.ReturnButton.Visible = 1;
            app.LogInButton.Visible = 1;
            
            app.AccountEditField.Visible = 1;
            app.PINEditField.Visible = 1;
            app.AccountLabel.Visible = 1;
            app.PINLabel.Visible = 1;
            app.EnterAccountTextArea.Visible = 1;
            
            app.EnterAccountLabel.Visible = 1;
            app.InsertCardLabel.Visible = 0;
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            if app.CheckBalanceLabel.Visible == 1
                app.QueryButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.WithdrawButton.Visible = 1;
                app.DepositButton.Visible = 1;
                app.ChangePINButton.Visible = 1;
                app.LogOutButton.Visible = 1;
                
                app.BalanceyuanLabel.Visible = 0;
                app.BalanceEditField.Visible = 0;
              
                app.TransactionLabel.Visible = 1;
                app.CheckBalanceLabel.Visible = 0;
                
                app.ReturnButton.Visible = 0;
            elseif app.TransferLabel.Visible == 1
                app.QueryButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.WithdrawButton.Visible = 1;
                app.DepositButton.Visible = 1;
                app.ChangePINButton.Visible = 1;
                app.LogOutButton.Visible = 1;
                app.TransferConfirmButton.Visible = 0;
                app.TransferConfirmButton.Enable = 1;
                
                app.AccounttransferredtoLabel.Visible = 0;
                app.TransferEditField.Visible = 0;
                app.TransferEditField.Value = 0;
                app.TransferAmountEditField.Visible = 0;
                app.TransferAmountEditField.Value = 0;
                app.TransferAmountyuanLabel.Visible = 0;
              
                app.TransactionLabel.Visible = 1;
                app.TransferLabel.Visible = 0;
                
                app.ReturnButton.Visible = 0;
            elseif app.ChangePINLabel.Visible == 1
                app.QueryButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.WithdrawButton.Visible = 1;
                app.DepositButton.Visible = 1;
                app.ChangePINButton.Visible = 1;
                app.LogOutButton.Visible = 1;
                app.ChangePINConfirmButton.Visible = 0;
                
                app.NewPINEditField.Visible = 0;
                app.NewPINLabel.Visible = 0;
                app.RepeatNewPINEditField.Visible = 0;
                app.RepeatNewPINLabel.Visible = 0;
              
                app.TransactionLabel.Visible = 1;
                app.ChangePINLabel.Visible = 0;
                
                app.NewPINEditField.Value = 0;
                app.RepeatNewPINEditField.Value = 0;
                
                app.ReturnButton.Visible = 0;
            elseif app.DepositLabel.Visible == 1
                if app.CNYImage.Visible == 1 || app.InvalidImage.Visible == 1
                    app.DepositLabel.Value = 'Please take your money away!';
                    return;
                end
                app.QueryButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.WithdrawButton.Visible = 1;
                app.DepositButton.Visible = 1;
                app.ChangePINButton.Visible = 1;
                app.LogOutButton.Visible = 1;
                app.DepositConfirmButton.Visible = 0;
                app.DepositConfirmButton.Enable = 1;
                
                app.DepositAmountEditField.Visible = 0;
                app.DepositAmountEditField.Value = 0;
                app.DepositAmountyuanLabel.Visible = 0;
              
                app.TransactionLabel.Visible = 1;
                app.DepositLabel.Visible = 0;
                app.CashInOutImage.Visible = 0;
                
                app.ReturnButton.Visible = 0; 
            elseif app.WithdrawLabel.Visible == 1
                if app.CNYImage.Visible == 1
                    app.WithdrawLabel.Value = 'Please take your money away!';
                    return;
                end
                app.QueryButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.WithdrawButton.Visible = 1;
                app.DepositButton.Visible = 1;
                app.ChangePINButton.Visible = 1;
                app.LogOutButton.Visible = 1;
                app.WithdrawConfirmButton.Visible = 0;
                app.WithdrawConfirmButton.Enable = 1;
                
                app.WithdrawAmountEditField.Visible = 0;
                app.WithdrawAmountEditField.Value = 0;
                app.WithdrawAmountyuanLabel.Visible = 0;
              
                app.TransactionLabel.Visible = 1;
                app.WithdrawLabel.Visible = 0;
                app.CashInOutImage.Visible = 0;
                
                app.ReturnButton.Visible = 0;  
            elseif app.AccountLabel.Visible == 1
                app.InsertImage.Visible = 1;
                app.InsertCardButton.Visible = 1;
                app.ReturnButton.Visible = 0;
                app.LogInButton.Visible = 0;
                
                app.AccountEditField.Visible = 0;
                app.PINEditField.Visible = 0;
                app.AccountLabel.Visible = 0;
                app.PINLabel.Visible = 0;
                
                app.EnterAccountLabel.Visible = 0;
                app.EnterAccountTextArea.Visible = 0;
                app.InsertCardLabel.Visible = 1;
            end
        end

        % Button pushed function: LogInButton
        function LogInButtonPushed(app, event)
            index = app.AccountEditField.Value - 100000 + 1;
            if index >= 1 && index <= app.AccountDatabase.account_cnt
                if app.AccountDatabase.account_data{index, 1} == app.AccountEditField.Value + 1000000
                    app.EnterAccountTextArea.Value = "Your account has been cancelled!";
                    return;
                elseif app.AccountDatabase.account_data{index, 1} == app.AccountEditField.Value + 2000000
                    app.EnterAccountTextArea.Value = "Your account has been freezed!";
                    return
                end
                
                if app.AccountDatabase.account_data{index, 2} == app.PINEditField.Value
                    app.ReturnButton.Visible = 0;
                    app.LogInButton.Visible = 0;
                    app.QueryButton.Visible = 1;
                    app.TransferButton.Visible = 1;
                    app.WithdrawButton.Visible = 1;
                    app.DepositButton.Visible = 1;
                    app.ChangePINButton.Visible = 1;
                    app.LogOutButton.Visible = 1;
                    
                    app.AccountEditField.Visible = 0;
                    app.PINEditField.Visible = 0;
                    app.AccountLabel.Visible = 0;
                    app.PINLabel.Visible = 0;
                  
                    app.TransactionLabel.Visible = 1;
                    app.EnterAccountTextArea.Visible = 0;
                    app.EnterAccountLabel.Visible = 0;
                    
                    app.current_index = index;
                 else
                     app.EnterAccountTextArea.Value = "Wrong Password! Try again!";
                 end
             else
                app.EnterAccountTextArea.Value = "Wrong ID! Try again!";
            end
            

        end

        % Button pushed function: LogOutButton
        function LogOutButtonPushed(app, event)
            app.ReturnButton.Visible = 1;
            app.LogInButton.Visible = 1;
            app.QueryButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.WithdrawButton.Visible = 0;
            app.DepositButton.Visible = 0;
            app.ChangePINButton.Visible = 0;
            app.LogOutButton.Visible = 0;
            
            app.AccountEditField.Visible = 1;
            app.PINEditField.Visible = 1;
            app.AccountEditField.Value = 0;
            app.PINEditField.Value = 0;
            app.AccountLabel.Visible = 1;
            app.PINLabel.Visible = 1;
          
            app.TransactionLabel.Visible = 0;
            app.EnterAccountTextArea.Visible = 1;
            app.EnterAccountLabel.Visible = 1;
            
            app.EnterAccountTextArea.Value = 'Please enter your account and PIN to log in.';
        end

        % Button pushed function: QueryButton
        function QueryButtonPushed(app, event)
            app.QueryButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.WithdrawButton.Visible = 0;
            app.DepositButton.Visible = 0;
            app.ChangePINButton.Visible = 0;
            app.LogOutButton.Visible = 0;
            
            app.BalanceyuanLabel.Visible = 1;
            app.BalanceEditField.Visible = 1;
            app.BalanceEditField.Value = app.AccountDatabase.appQuery(app.current_index);
            
          
            app.TransactionLabel.Visible = 0;
            app.CheckBalanceLabel.Visible = 1;
            
            app.ReturnButton.Visible = 1;
        end

        % Button pushed function: TransferButton
        function TransferButtonPushed(app, event)
            app.QueryButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.WithdrawButton.Visible = 0;
            app.DepositButton.Visible = 0;
            app.ChangePINButton.Visible = 0;
            app.LogOutButton.Visible = 0;
            app.TransferConfirmButton.Visible = 1;
            
            app.AccounttransferredtoLabel.Visible = 1;
            app.TransferEditField.Visible = 1;
            app.TransferAmountEditField.Visible = 1;
            app.TransferAmountyuanLabel.Visible = 1;
          
            app.TransactionLabel.Visible = 0;
            app.TransferLabel.Visible = 1;
            app.TransferLabel.Value = 'Please fill in the account you want to transfer to and the amount.';
            
            app.ReturnButton.Visible = 1;
        end

        % Button pushed function: WithdrawButton
        function WithdrawButtonPushed(app, event)
            app.QueryButton.Visible = 0;
            app.WithdrawButton.Visible = 0;
            app.DepositButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.ChangePINButton.Visible = 0;
            app.LogOutButton.Visible = 0;
            app.WithdrawConfirmButton.Visible = 1;
            
            app.WithdrawAmountEditField.Visible = 1;
            app.WithdrawAmountyuanLabel.Visible = 1;
          
            app.TransactionLabel.Visible = 0;
            app.WithdrawLabel.Visible = 1;
            app.WithdrawLabel.Value = 'Please fill in the amount you want to withdraw.';
            app.CashInOutImage.Visible = 1;
            
            app.ReturnButton.Visible = 1;
        end

        % Button pushed function: DepositButton
        function DepositButtonPushed(app, event)
            app.QueryButton.Visible = 0;
            app.WithdrawButton.Visible = 0;
            app.DepositButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.ChangePINButton.Visible = 0;
            app.LogOutButton.Visible = 0;
            app.DepositConfirmButton.Visible = 1;
            
            app.DepositAmountEditField.Visible = 1;
            app.DepositAmountyuanLabel.Visible = 1;
          
            app.TransactionLabel.Visible = 0;
            app.DepositLabel.Visible = 1;
            app.DepositLabel.Value = 'Please fill in the amount you want to deposit.';
            app.CashInOutImage.Visible = 1;
            
            app.ReturnButton.Visible = 1;
        end

        % Button pushed function: ChangePINButton
        function ChangePINButtonPushed(app, event)
            app.QueryButton.Visible = 0;
            app.WithdrawButton.Visible = 0;
            app.DepositButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.ChangePINButton.Visible = 0;
            app.LogOutButton.Visible = 0;
            app.ChangePINConfirmButton.Visible = 1;
            
            app.NewPINEditField.Visible = 1;
            app.NewPINLabel.Visible = 1;
            app.RepeatNewPINEditField.Visible = 1;
            app.RepeatNewPINLabel.Visible = 1;
          
            app.TransactionLabel.Visible = 0;
            app.ChangePINLabel.Visible = 1;
            
            app.ChangePINConfirmButton.Enable = 1;
            app.NewPINEditField.Editable = 1;
            app.RepeatNewPINEditField.Editable = 1;
            
            app.ChangePINLabel.Visible = 1;
            app.ChangePINLabel.Value = 'Please set your new 6-bit password.';
            
            app.ReturnButton.Visible = 1;
        end

        % Button pushed function: TransferConfirmButton
        function TransferConfirmButtonPushed(app, event)
            if app.TransferEditField.Value <= 99999 ||...
                    app.TransferEditField.Value > app.AccountDatabase.account_cnt - 1 + 100000 ||...
                    app.TransferEditField.Value == app.current_index + 100000 - 1
                app.TransferLabel.Value = 'Wrong account!';
            else
                if app.AccountDatabase.CheckAccount(app.TransferEditField.Value - 100000 + 1, app.TransferEditField.Value) == 0
                    app.TransferLabel.Value = 'The account has been freezed or cancelled!';
                    return;
                end
                
                if app.AccountDatabase.appQuery(app.current_index) < app.TransferAmountEditField.Value 
                    app.TransferLabel.Value = 'Not enough money!';
                elseif app.TransferAmountEditField.Value <= 0
                    app.TransferLabel.Value = 'The amount should be positive!';
                else
                    app.TransferConfirmButton.Enable = 0;
                    app.AccountDatabase.appTransfer(app.current_index,app.TransferEditField.Value - 100000 + 1,app.TransferAmountEditField.Value);
                    app.AccountDatabase.Record(app.current_index + 100000 - 1, 'T', app.TransferAmountEditField.Value, app.TransferEditField.Value);
                    app.TransferLabel.Value = ['Transfer successfully!' ...
                        ' If you want to transfer again, please return and continue.'];
                    
                end
            end
        end

        % Button pushed function: ChangePINConfirmButton
        function ChangePINConfirmButtonPushed(app, event)
            if app.NewPINEditField.Value > 99999 && app.NewPINEditField.Value < 1000000
                if app.NewPINEditField.Value == app.RepeatNewPINEditField.Value
                    app.ChangePINConfirmButton.Enable = 0;
                    app.AccountDatabase.changeAccountPIN(app.current_index,app.NewPINEditField.Value);
                    app.RepeatNewPINEditField.Editable = 0;
                    app.NewPINEditField.Editable = 0;                    
                    app.ChangePINLabel.Value = 'Change sucessfully!';
                else
                    app.ChangePINLabel.Value = 'Check your new password again!';
                end
            else
                app.ChangePINLabel.Value = 'Your new password is not 6-bit. Try again!';
            end
        end

        % Button pushed function: DepositConfirmButton
        function DepositConfirmButtonPushed(app, event)
            if app.DepositAmountEditField.Value <= 0
                app.DepositLabel.Value = 'The amount should be positive!';
            elseif app.InvalidImage.Visible == 1
                app.DepositLabel.Value = 'The money is invalid!';
            elseif app.CNYImage.Visible == 0
                app.DepositLabel.Value = 'Please put your cash below.';
            elseif mod(app.DepositAmountEditField.Value, 100) ~= 0
                app.DepositLabel.Value = 'You can only deposit multiples of 100CNY.';
            else
                app.DepositConfirmButton.Enable = 0;
                app.CNYImage.Visible = 0;
                app.AccountDatabase.Deposit(app.current_index,app.DepositAmountEditField.Value);
                app.AccountDatabase.Record(app.current_index + 100000 - 1, 'D', app.DepositAmountEditField.Value, 0);
                app.DepositLabel.Value = ['Deposit successfully!' ...
                    ' If you want to deposit again, please return and continue.'];
                
            end
        end

        % Button pushed function: WithdrawConfirmButton
        function WithdrawConfirmButtonPushed(app, event)
            if app.WithdrawAmountEditField.Value <= 0
                app.WithdrawLabel.Value = 'The amount should be positive!';
            elseif app.AccountDatabase.appQuery(app.current_index) < app.WithdrawAmountEditField.Value 
                app.WithdrawLabel.Value = 'Not enough money!';
            elseif mod(app.WithdrawAmountEditField.Value, 100) ~= 0
                app.WithdrawLabel.Value = 'You can only withdraw multiples of 100CNY.';
            else
                app.WithdrawConfirmButton.Enable = 0;
                app.CNYImage.Visible = 1;
                app.AccountDatabase.Withdraw(app.current_index,app.WithdrawAmountEditField.Value);
                app.AccountDatabase.Record(app.current_index + 100000 - 1, 'W', app.WithdrawAmountEditField.Value, 0);
                app.WithdrawLabel.Value = ['Withdraw successfully!' ...
                    ' If you want to withdraw again, please return and continue.'];
                
            end
        end

        % Image clicked function: CNYImage
        function CNYImageClicked(app, event)
            if app.WithdrawLabel.Visible == 1 || app.DepositLabel.Visible == 1
                app.CNYImage.Visible = 0;
            end
        end

        % Callback function: ATMUIFigure, ATMUIFigure
        function ATMUIFigureWindowKeyPress(app, event)
            key = event.Key;
            if app.InsertCardButton.Visible == 1
                if key == 'n'
                    app.CardImage.Visible = 1;
                elseif key == 'c'
                    app.CardImage.Visible = 0;
                end
            elseif app.DepositConfirmButton.Visible == 1
                if key == 'm'
                    app.CNYImage.Visible = 1;
                    app.InvalidImage.Visible = 0;
                elseif key == 'n'
                    app.InvalidImage.Visible = 1;
                    app.CNYImage.Visible = 0;
                end
            end
        end

        % Image clicked function: InvalidImage
        function InvalidImageClicked(app, event)
            if app.DepositLabel.Visible == 1
                app.InvalidImage.Visible = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ATMUIFigure = uifigure('Visible', 'off');
            app.ATMUIFigure.Name = 'ATM';
            app.ATMUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @ATMUIFigureWindowKeyPress, true);
            app.ATMUIFigure.KeyPressFcn = createCallbackFcn(app, @ATMUIFigureWindowKeyPress, true);
            app.ATMLabel = uilabel(app.ATMUIFigure);
            app.ATMLabel.Text = 'ATM';
            app.WelcomeLabel = uilabel(app.ATMUIFigure);
            app.WelcomeLabel.Text = 'Welcome to Shanghaitech Bank!';
            app.PINEditField = uieditfield(app.ATMUIFigure, 'numeric');
            app.PINEditField.ValueDisplayFormat = '%.0f';
            app.PINEditField.Visible = 'off';
            app.AccountEditField = uieditfield(app.ATMUIFigure, 'numeric');
            app.AccountEditField.ValueDisplayFormat = '%.0f';
            app.AccountEditField.Visible = 'off';
            app.ReturnButton = uibutton(app.ATMUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Visible = 'off';
            app.ReturnButton.Text = 'Return';
            app.AccountLabel = uilabel(app.ATMUIFigure);
            app.AccountLabel.Visible = 'off';
            app.AccountLabel.Text = 'Account :';
            app.PINLabel = uilabel(app.ATMUIFigure);
            app.PINLabel.Visible = 'off';
            app.PINLabel.Text = 'PIN :';
            app.LogInButton = uibutton(app.ATMUIFigure, 'push');
            app.LogInButton.ButtonPushedFcn = createCallbackFcn(app, @LogInButtonPushed, true);
            app.LogInButton.Visible = 'off';
            app.LogInButton.Text = 'Log In';
            app.TransferButton = uibutton(app.ATMUIFigure, 'push');
            app.TransferButton.ButtonPushedFcn = createCallbackFcn(app, @TransferButtonPushed, true);
            app.TransferButton.Visible = 'off';
            app.TransferButton.Text = 'Transfer';
            app.QueryButton = uibutton(app.ATMUIFigure, 'push');
            app.QueryButton.ButtonPushedFcn = createCallbackFcn(app, @QueryButtonPushed, true);
            app.QueryButton.Visible = 'off';
            app.QueryButton.Text = 'Query';
            app.ChangePINButton = uibutton(app.ATMUIFigure, 'push');
            app.ChangePINButton.ButtonPushedFcn = createCallbackFcn(app, @ChangePINButtonPushed, true);
            app.ChangePINButton.Visible = 'off';
            app.ChangePINButton.Text = 'Change PIN';
            app.LogOutButton = uibutton(app.ATMUIFigure, 'push');
            app.LogOutButton.ButtonPushedFcn = createCallbackFcn(app, @LogOutButtonPushed, true);
            app.LogOutButton.Visible = 'off';
            app.LogOutButton.Text = 'Log Out';
            app.TransactionLabel = uilabel(app.ATMUIFigure);
            app.TransactionLabel.Visible = 'off';
            app.TransactionLabel.Text = 'Please select your transaction.';
            app.BalanceEditField = uieditfield(app.ATMUIFigure, 'numeric');
            app.BalanceEditField.ValueDisplayFormat = '%.2f';
            app.BalanceEditField.Editable = 'off';
            app.BalanceEditField.Visible = 'off';
            app.BalanceyuanLabel = uilabel(app.ATMUIFigure);
            app.BalanceyuanLabel.Visible = 'off';
            app.BalanceyuanLabel.Text = 'Balance (yuan) :';
            app.CheckBalanceLabel = uilabel(app.ATMUIFigure);
            app.CheckBalanceLabel.Visible = 'off';
            app.CheckBalanceLabel.Text = 'You can check your balance here.';
            app.TransferEditField = uieditfield(app.ATMUIFigure, 'numeric');
            app.TransferEditField.ValueDisplayFormat = '%.0f';
            app.TransferEditField.Visible = 'off';
            app.AccounttransferredtoLabel = uilabel(app.ATMUIFigure);
            app.AccounttransferredtoLabel.Visible = 'off';
            app.AccounttransferredtoLabel.Text = 'Account transferred to :';
            app.TransferAmountEditField = uieditfield(app.ATMUIFigure, 'numeric');
            app.TransferAmountEditField.ValueDisplayFormat = '%.2f';
            app.TransferAmountEditField.Visible = 'off';
            app.TransferAmountyuanLabel = uilabel(app.ATMUIFigure);
            app.TransferAmountyuanLabel.Visible = 'off';
            app.TransferAmountyuanLabel.Text = 'Amount (yuan) :';
            app.TransferConfirmButton = uibutton(app.ATMUIFigure, 'push');
            app.TransferConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @TransferConfirmButtonPushed, true);
            app.TransferConfirmButton.Visible = 'off';
            app.TransferConfirmButton.Text = 'Confirm';
            app.NewPINLabel = uilabel(app.ATMUIFigure);
            app.NewPINLabel.Visible = 'off';
            app.NewPINLabel.Text = 'New PIN :';
            app.NewPINEditField = uieditfield(app.ATMUIFigure, 'numeric');
            app.NewPINEditField.ValueDisplayFormat = '%.0f';
            app.NewPINEditField.Visible = 'off';
            app.RepeatNewPINLabel = uilabel(app.ATMUIFigure);
            app.RepeatNewPINLabel.Visible = 'off';
            app.RepeatNewPINLabel.Text = 'Repeat New PIN :';
            app.RepeatNewPINEditField = uieditfield(app.ATMUIFigure, 'numeric');
            app.RepeatNewPINEditField.ValueDisplayFormat = '%.0f';
            app.RepeatNewPINEditField.Visible = 'off';
            app.ChangePINConfirmButton = uibutton(app.ATMUIFigure, 'push');
            app.ChangePINConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ChangePINConfirmButtonPushed, true);
            app.ChangePINConfirmButton.Visible = 'off';
            app.ChangePINConfirmButton.Text = 'Confirm';
            app.EnterAccountTextArea = uitextarea(app.ATMUIFigure);
            app.EnterAccountTextArea.Editable = 'off';
            app.EnterAccountTextArea.Visible = 'off';
            app.EnterAccountTextArea.Value = {'Please enter your account and PIN.'};
            app.EnterAccountLabel = uilabel(app.ATMUIFigure);
            app.EnterAccountLabel.Visible = 'off';
            app.EnterAccountLabel.Text = 'Please enter your account and PIN.';
            app.TransferLabel = uitextarea(app.ATMUIFigure);
            app.TransferLabel.Editable = 'off';
            app.TransferLabel.Visible = 'off';
            app.TransferLabel.Value = {'Please fill in the account you want to transfer to and the amount.'};
            app.ChangePINLabel = uitextarea(app.ATMUIFigure);
            app.ChangePINLabel.Editable = 'off';
            app.ChangePINLabel.Visible = 'off';
            app.ChangePINLabel.Value = {'Please enter your new PIN and repeat it.'};
            app.InsertCardButton = uibutton(app.ATMUIFigure, 'push');
            app.InsertCardButton.ButtonPushedFcn = createCallbackFcn(app, @InsertCardButtonPushed, true);
            app.InsertCardButton.Text = 'Insert  Card';
            app.InsertCardLabel = uilabel(app.ATMUIFigure);
            app.InsertCardLabel.Text = 'Please insert your shanghaitech card first.';
            app.WithdrawButton = uibutton(app.ATMUIFigure, 'push');
            app.WithdrawButton.ButtonPushedFcn = createCallbackFcn(app, @WithdrawButtonPushed, true);
            app.WithdrawButton.Visible = 'off';
            app.WithdrawButton.Text = 'Withdraw';
            app.DepositButton = uibutton(app.ATMUIFigure, 'push');
            app.DepositButton.ButtonPushedFcn = createCallbackFcn(app, @DepositButtonPushed, true);
            app.DepositButton.Visible = 'off';
            app.DepositButton.Text = 'Deposit';
            app.WithdrawConfirmButton = uibutton(app.ATMUIFigure, 'push');
            app.WithdrawConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @WithdrawConfirmButtonPushed, true);
            app.WithdrawConfirmButton.Visible = 'off';
            app.WithdrawConfirmButton.Text = 'Confirm';
            app.WithdrawAmountEditField = uieditfield(app.ATMUIFigure, 'numeric');
            app.WithdrawAmountEditField.ValueDisplayFormat = '%.2f';
            app.WithdrawAmountEditField.Visible = 'off';
            app.WithdrawAmountyuanLabel = uilabel(app.ATMUIFigure);
            app.WithdrawAmountyuanLabel.Visible = 'off';
            app.WithdrawAmountyuanLabel.Text = 'Amount (yuan) :';
            app.DepositConfirmButton = uibutton(app.ATMUIFigure, 'push');
            app.DepositConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @DepositConfirmButtonPushed, true);
            app.DepositConfirmButton.Visible = 'off';
            app.DepositConfirmButton.Text = 'Confirm';
            app.DepositAmountEditField = uieditfield(app.ATMUIFigure, 'numeric');
            app.DepositAmountEditField.ValueDisplayFormat = '%.2f';
            app.DepositAmountEditField.Visible = 'off';
            app.DepositAmountyuanLabel = uilabel(app.ATMUIFigure);
            app.DepositAmountyuanLabel.Visible = 'off';
            app.DepositAmountyuanLabel.Text = 'Amount (yuan) :';
            app.DepositLabel = uitextarea(app.ATMUIFigure);
            app.DepositLabel.Editable = 'off';
            app.DepositLabel.Visible = 'off';
            app.DepositLabel.Value = {'Please fill in the amount you want to deposit.'};
            app.WithdrawLabel = uitextarea(app.ATMUIFigure);
            app.WithdrawLabel.Editable = 'off';
            app.WithdrawLabel.Visible = 'off';
            app.WithdrawLabel.Value = {'Please fill in the amount you want to withdraw.'};
            app.InsertImage = uiimage(app.ATMUIFigure);
            app.InsertImage.ImageSource = 'insert.jpg';
            app.CashInOutImage = uiimage(app.ATMUIFigure);
            app.CashInOutImage.Visible = 'off';
            app.CashInOutImage.ImageSource = 'cash_in_out.JPG';
            app.CNYImage = uiimage(app.ATMUIFigure);
            app.CNYImage.ImageClickedFcn = createCallbackFcn(app, @CNYImageClicked, true);
            app.CNYImage.Visible = 'off';
            app.CNYImage.ImageSource = '100CNY.jpg';
            app.CardImage = uiimage(app.ATMUIFigure);
            app.CardImage.Visible = 'off';
            app.CardImage.ImageSource = 'invalidCNY.jpg';
            app.InvalidImage = uiimage(app.ATMUIFigure);
            app.InvalidImage.ImageClickedFcn = createCallbackFcn(app, @InvalidImageClicked, true);
            app.InvalidImage.Visible = 'off';
            app.InvalidImage.ImageSource = 'invalid.jpg';
            % Show the figure after all components are created
            app.ATMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ATM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ATMUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ATMUIFigure)
        end
    end
end