classdef Account < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AccountUIFigure               matlab.ui.Figure
        TransactionLabel              matlab.ui.control.TextArea
        UnFreezeButton                matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        ChangePINLabel                matlab.ui.control.TextArea
        TransferLabel                 matlab.ui.control.TextArea
        EnterAccountLabel             matlab.ui.control.Label
        EnterAccountTextArea          matlab.ui.control.TextArea
        ChangePINConfirmButton        matlab.ui.control.Button
        RepeatNewPINEditField         matlab.ui.control.NumericEditField
        RepeatNewPINLabel             matlab.ui.control.Label
        NewPINEditField               matlab.ui.control.NumericEditField
        NewPINLabel                   matlab.ui.control.Label
        TransferConfirmButton         matlab.ui.control.Button
        TransferAmountyuanLabel       matlab.ui.control.Label
        TransferAmountEditField       matlab.ui.control.NumericEditField
        AccounttransferredtoLabel     matlab.ui.control.Label
        TransferEditField             matlab.ui.control.NumericEditField
        CheckBalanceLabel             matlab.ui.control.Label
        BalanceyuanLabel              matlab.ui.control.Label
        BalanceEditField              matlab.ui.control.NumericEditField
        LogOutButton                  matlab.ui.control.Button
        ChangePINButton               matlab.ui.control.Button
        QueryButton                   matlab.ui.control.Button
        TransferButton                matlab.ui.control.Button
        ManageButton                  matlab.ui.control.Button
        AccountLabel                  matlab.ui.control.Label
        ReturnButton                  matlab.ui.control.Button
        AccountEditField              matlab.ui.control.NumericEditField
        ShanghaitechBankAccountLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        AccountDatabase;
        current_index = 0;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            if app.CheckBalanceLabel.Visible == 1
                app.QueryButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.ChangePINButton.Visible = 1;
                app.LogOutButton.Visible = 1;
                app.CancelButton.Visible = 1;
                app.UnFreezeButton.Visible = 1;
                
                app.BalanceyuanLabel.Visible = 0;
                app.BalanceEditField.Visible = 0;
              
                app.TransactionLabel.Visible = 1;
                app.CheckBalanceLabel.Visible = 0;
                
                app.ReturnButton.Visible = 0;
            elseif app.TransferLabel.Visible == 1
                app.QueryButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.ChangePINButton.Visible = 1;
                app.LogOutButton.Visible = 1;
                app.CancelButton.Visible = 1;
                app.UnFreezeButton.Visible = 1;
                app.TransferConfirmButton.Visible = 0;
                app.TransferConfirmButton.Enable = 1;
                
                app.AccounttransferredtoLabel.Visible = 0;
                app.TransferEditField.Visible = 0;
                app.TransferEditField.Value = 0;
                app.TransferAmountEditField.Visible = 0;
                app.TransferAmountEditField.Value = 0;
                app.TransferAmountyuanLabel.Visible = 0;
              
                app.TransactionLabel.Visible = 1;
                app.TransferLabel.Visible = 0;
                
                app.ReturnButton.Visible = 0;
            elseif app.ChangePINLabel.Visible == 1
                app.QueryButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.ChangePINButton.Visible = 1;
                app.LogOutButton.Visible = 1;
                app.CancelButton.Visible = 1;
                app.UnFreezeButton.Visible = 1;
                app.ChangePINConfirmButton.Visible = 0;
                
                app.NewPINEditField.Visible = 0;
                app.NewPINLabel.Visible = 0;
                app.RepeatNewPINEditField.Visible = 0;
                app.RepeatNewPINLabel.Visible = 0;
              
                app.TransactionLabel.Visible = 1;
                app.ChangePINLabel.Visible = 0;
                
                app.NewPINEditField.Value = 0;
                app.RepeatNewPINEditField.Value = 0;
                
                app.ReturnButton.Visible = 0;
            elseif app.AccountLabel.Visible == 1
                delete(app);
            end
        end

        % Button pushed function: ManageButton
        function ManageButtonPushed(app, event)
            index = app.AccountEditField.Value - 100000 + 1;
            
            if index >= 1 && index <= app.AccountDatabase.account_cnt
                if app.AccountDatabase.account_data{index, 1} == app.AccountEditField.Value + 1000000
                    app.EnterAccountTextArea.Value = "This account has been cancelled!";
                    return;
                elseif app.AccountDatabase.account_data{index, 1} == app.AccountEditField.Value + 2000000
                    app.TransactionLabel.Value = "This account has been freezed!";
                    app.CancelButton.Enable = 0;
                    app.QueryButton.Enable = 0;
                    app.TransferButton.Enable = 0;
                    app.ChangePINButton.Enable = 0;
                end
                
                app.ReturnButton.Visible = 0;
                app.ManageButton.Visible = 0;
                app.QueryButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.ChangePINButton.Visible = 1;
                app.LogOutButton.Visible = 1;
                app.CancelButton.Visible = 1;
                app.UnFreezeButton.Visible = 1;
                
                app.AccountEditField.Visible = 0;
                app.AccountLabel.Visible = 0;
              
                app.TransactionLabel.Visible = 1;
                app.EnterAccountTextArea.Visible = 0;
                app.EnterAccountLabel.Visible = 0;
                
                app.current_index = index;
             else
                app.EnterAccountTextArea.Value = "Wrong ID! Try again!";
            end
            

        end

        % Button pushed function: LogOutButton
        function LogOutButtonPushed(app, event)
            app.ReturnButton.Visible = 1;
            app.ManageButton.Visible = 1;
            app.QueryButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.ChangePINButton.Visible = 0;
            app.LogOutButton.Visible = 0;
            app.CancelButton.Visible = 0;
            app.UnFreezeButton.Visible = 0;
            
            app.CancelButton.Enable = 1;
            app.QueryButton.Enable = 1;
            app.TransferButton.Enable = 1;
            app.ChangePINButton.Enable = 1;
            app.UnFreezeButton.Enable = 1;
            
            app.AccountEditField.Visible = 1;
            app.AccountEditField.Value = 0;
            app.AccountLabel.Visible = 1;
          
            app.TransactionLabel.Visible = 0;
            app.TransactionLabel.Value = "Please select your transaction.";
            app.EnterAccountTextArea.Visible = 1;
            app.EnterAccountLabel.Visible = 1;
            
            app.EnterAccountTextArea.Value = 'Please enter the account you want to manage.';
        end

        % Button pushed function: QueryButton
        function QueryButtonPushed(app, event)
            app.QueryButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.ChangePINButton.Visible = 0;
            app.LogOutButton.Visible = 0;
            app.CancelButton.Visible = 0;
            app.UnFreezeButton.Visible = 0;
            
            app.BalanceyuanLabel.Visible = 1;
            app.BalanceEditField.Visible = 1;
            app.BalanceEditField.Value = app.AccountDatabase.appQuery(app.current_index);
            
          
            app.TransactionLabel.Visible = 0;
            app.CheckBalanceLabel.Visible = 1;
            
            app.ReturnButton.Visible = 1;
        end

        % Button pushed function: TransferButton
        function TransferButtonPushed(app, event)
            app.QueryButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.ChangePINButton.Visible = 0;
            app.LogOutButton.Visible = 0;
            app.CancelButton.Visible = 0;
            app.UnFreezeButton.Visible = 0;
            app.TransferConfirmButton.Visible = 1;
            
            app.AccounttransferredtoLabel.Visible = 1;
            app.TransferEditField.Visible = 1;
            app.TransferAmountEditField.Visible = 1;
            app.TransferAmountyuanLabel.Visible = 1;
          
            app.TransactionLabel.Visible = 0;
            app.TransferLabel.Visible = 1;
            app.TransferLabel.Value = 'Please fill in the account you want to transfer to and the amount.';
            
            app.ReturnButton.Visible = 1;
        end

        % Button pushed function: ChangePINButton
        function ChangePINButtonPushed(app, event)
            app.QueryButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.ChangePINButton.Visible = 0;
            app.LogOutButton.Visible = 0;
            app.CancelButton.Visible = 0;
            app.UnFreezeButton.Visible = 0;
            app.ChangePINConfirmButton.Visible = 1;
            
            app.NewPINEditField.Visible = 1;
            app.NewPINLabel.Visible = 1;
            app.RepeatNewPINEditField.Visible = 1;
            app.RepeatNewPINLabel.Visible = 1;
          
            app.TransactionLabel.Visible = 0;
            app.ChangePINLabel.Visible = 1;
            
            app.ChangePINConfirmButton.Enable = 1;
            app.NewPINEditField.Editable = 1;
            app.RepeatNewPINEditField.Editable = 1;
            
            app.ChangePINLabel.Visible = 1;
            app.ChangePINLabel.Value = 'Please set your new 6-bit password.';
            
            app.ReturnButton.Visible = 1;
        end

        % Button pushed function: TransferConfirmButton
        function TransferConfirmButtonPushed(app, event)
            if app.TransferEditField.Value <= 99999 ||...
                    app.TransferEditField.Value > app.AccountDatabase.account_cnt - 1 + 100000 ||...
                    app.TransferEditField.Value == app.current_index + 100000 - 1
                app.TransferLabel.Value = 'Wrong account!';
            else
                if app.AccountDatabase.CheckAccount(app.TransferEditField.Value - 100000 + 1, app.TransferEditField.Value) == 0
                    app.TransferLabel.Value = 'The account has been freezed or cancelled!';
                    return;
                end
                
                if app.AccountDatabase.appQuery(app.current_index) < app.TransferAmountEditField.Value 
                    app.TransferLabel.Value = 'Not enough money!';
                elseif app.TransferAmountEditField.Value <= 0
                    app.TransferLabel.Value = 'The ammount should be positive!';
                else
                    app.TransferConfirmButton.Enable = 0;
                    app.AccountDatabase.appTransfer(app.current_index,app.TransferEditField.Value - 100000 + 1,app.TransferAmountEditField.Value);
                    app.AccountDatabase.Record(app.current_index + 100000 - 1, 'T', app.TransferAmountEditField.Value, app.TransferEditField.Value);
                    app.TransferLabel.Value = ['Transfer successfully!' ...
                        'If you want to transfer again, please return and continue.'];
                    
                end
            end
        end

        % Button pushed function: ChangePINConfirmButton
        function ChangePINConfirmButtonPushed(app, event)
            if app.NewPINEditField.Value > 99999 && app.NewPINEditField.Value < 1000000
                if app.NewPINEditField.Value == app.RepeatNewPINEditField.Value
                    app.ChangePINConfirmButton.Enable = 0;
                    app.AccountDatabase.changeAccountPIN(app.current_index,app.NewPINEditField.Value);
                    app.RepeatNewPINEditField.Editable = 0;
                    app.NewPINEditField.Editable = 0;
                    
                    app.ChangePINLabel.Value = 'Change sucessfully!';
                else
                    app.ChangePINLabel.Value = 'Check your new PIN again!';
                end
            else
                app.ChangePINLabel.Value = 'Your new PIN is not 6-bit. Try again!';
            end
        end

        % Button pushed function: UnFreezeButton
        function UnFreezeButtonPushed(app, event)
            if app.QueryButton.Enable == 1
                app.AccountDatabase.Freeze(app.current_index);
                app.CancelButton.Enable = 0;
                app.QueryButton.Enable = 0;
                app.TransferButton.Enable = 0;
                app.ChangePINButton.Enable = 0;
                app.TransactionLabel.Value = "This account has been freezed!";
            else
                app.AccountDatabase.Unfreeze(app.current_index);
                app.CancelButton.Enable = 1;
                app.QueryButton.Enable = 1;
                app.TransferButton.Enable = 1;
                app.ChangePINButton.Enable = 1;
                app.TransactionLabel.Value = "Please select your transaction.";
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.AccountDatabase.Cancel(app.current_index);
            app.CancelButton.Enable = 0;
            app.QueryButton.Enable = 0;
            app.TransferButton.Enable = 0;
            app.ChangePINButton.Enable = 0;
            app.UnFreezeButton.Enable = 0;
            app.TransactionLabel.Value = "This account has been cancelled!";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AccountUIFigure = uifigure('Visible', 'off');
            app.AccountUIFigure.Name = 'Account';
            app.ShanghaitechBankAccountLabel = uilabel(app.AccountUIFigure);
            app.ShanghaitechBankAccountLabel.Text = 'Shanghaitech Bank Account';
            app.AccountEditField = uieditfield(app.AccountUIFigure, 'numeric');
            app.AccountEditField.ValueDisplayFormat = '%.0f';
            app.ReturnButton = uibutton(app.AccountUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.AccountLabel = uilabel(app.AccountUIFigure);
            app.AccountLabel.Text = 'Account :';
            app.ManageButton = uibutton(app.AccountUIFigure, 'push');
            app.ManageButton.ButtonPushedFcn = createCallbackFcn(app, @ManageButtonPushed, true);
            app.ManageButton.Text = 'Manage';
            app.TransferButton = uibutton(app.AccountUIFigure, 'push');
            app.TransferButton.ButtonPushedFcn = createCallbackFcn(app, @TransferButtonPushed, true);
            app.TransferButton.Visible = 'off';
            app.TransferButton.Text = 'Transfer';
            app.QueryButton = uibutton(app.AccountUIFigure, 'push');
            app.QueryButton.ButtonPushedFcn = createCallbackFcn(app, @QueryButtonPushed, true);
            app.QueryButton.Visible = 'off';
            app.QueryButton.Text = 'Query';
            app.ChangePINButton = uibutton(app.AccountUIFigure, 'push');
            app.ChangePINButton.ButtonPushedFcn = createCallbackFcn(app, @ChangePINButtonPushed, true);
            app.ChangePINButton.Visible = 'off';
            app.ChangePINButton.Text = 'Change PIN';
            app.LogOutButton = uibutton(app.AccountUIFigure, 'push');
            app.LogOutButton.ButtonPushedFcn = createCallbackFcn(app, @LogOutButtonPushed, true);
            app.LogOutButton.Visible = 'off';
            app.LogOutButton.Text = 'Log Out';
            app.BalanceEditField = uieditfield(app.AccountUIFigure, 'numeric');
            app.BalanceEditField.ValueDisplayFormat = '%.2f';
            app.BalanceEditField.Editable = 'off';
            app.BalanceEditField.Visible = 'off';
            app.BalanceyuanLabel = uilabel(app.AccountUIFigure);
            app.BalanceyuanLabel.Visible = 'off';
            app.BalanceyuanLabel.Text = 'Balance (yuan) :';
            app.CheckBalanceLabel = uilabel(app.AccountUIFigure);
            app.CheckBalanceLabel.Visible = 'off';
            app.CheckBalanceLabel.Text = 'You can check your balance here.';
            app.TransferEditField = uieditfield(app.AccountUIFigure, 'numeric');
            app.TransferEditField.ValueDisplayFormat = '%.0f';
            app.TransferEditField.Visible = 'off';
            app.AccounttransferredtoLabel = uilabel(app.AccountUIFigure);
            app.AccounttransferredtoLabel.Visible = 'off';
            app.AccounttransferredtoLabel.Text = 'Account transferred to :';
            app.TransferAmountEditField = uieditfield(app.AccountUIFigure, 'numeric');
            app.TransferAmountEditField.ValueDisplayFormat = '%.2f';
            app.TransferAmountEditField.Visible = 'off';
            app.TransferAmountyuanLabel = uilabel(app.AccountUIFigure);
            app.TransferAmountyuanLabel.Visible = 'off';
            app.TransferAmountyuanLabel.Text = 'Amount (yuan) :';
            app.TransferConfirmButton = uibutton(app.AccountUIFigure, 'push');
            app.TransferConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @TransferConfirmButtonPushed, true);
            app.TransferConfirmButton.Visible = 'off';
            app.TransferConfirmButton.Text = 'Confirm';
            app.NewPINLabel = uilabel(app.AccountUIFigure);
            app.NewPINLabel.Visible = 'off';
            app.NewPINLabel.Text = 'New PIN :';
            app.NewPINEditField = uieditfield(app.AccountUIFigure, 'numeric');
            app.NewPINEditField.ValueDisplayFormat = '%.0f';
            app.NewPINEditField.Visible = 'off';
            app.RepeatNewPINLabel = uilabel(app.AccountUIFigure);
            app.RepeatNewPINLabel.Visible = 'off';
            app.RepeatNewPINLabel.Text = 'Repeat New PIN :';
            app.RepeatNewPINEditField = uieditfield(app.AccountUIFigure, 'numeric');
            app.RepeatNewPINEditField.ValueDisplayFormat = '%.0f';
            app.RepeatNewPINEditField.Visible = 'off';
            app.ChangePINConfirmButton = uibutton(app.AccountUIFigure, 'push');
            app.ChangePINConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ChangePINConfirmButtonPushed, true);
            app.ChangePINConfirmButton.Visible = 'off';
            app.ChangePINConfirmButton.Text = 'Confirm';
            app.EnterAccountTextArea = uitextarea(app.AccountUIFigure);
            app.EnterAccountTextArea.Editable = 'off';
            app.EnterAccountTextArea.Value = {'Please enter the account you want to manage.'};
            app.EnterAccountLabel = uilabel(app.AccountUIFigure);
            app.EnterAccountLabel.Text = 'Please enter the account you want to manage.';
            app.TransferLabel = uitextarea(app.AccountUIFigure);
            app.TransferLabel.Editable = 'off';
            app.TransferLabel.Visible = 'off';
            app.TransferLabel.Value = {'Please fill in the account you want to transfer to and the amount.'};
            app.ChangePINLabel = uitextarea(app.AccountUIFigure);
            app.ChangePINLabel.Editable = 'off';
            app.ChangePINLabel.Visible = 'off';
            app.ChangePINLabel.Value = {'Please enter your new PIN and repeat it.'};
            app.CancelButton = uibutton(app.AccountUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Visible = 'off';
            app.CancelButton.Text = 'Cancel';
            app.UnFreezeButton = uibutton(app.AccountUIFigure, 'push');
            app.UnFreezeButton.ButtonPushedFcn = createCallbackFcn(app, @UnFreezeButtonPushed, true);
            app.UnFreezeButton.Visible = 'off';
            app.UnFreezeButton.Text = '(Un)/Freeze';
            app.TransactionLabel = uitextarea(app.AccountUIFigure);
            app.TransactionLabel.Visible = 'off';
            app.TransactionLabel.Value = {'Please select your transaction.'};
            % Show the figure after all components are created
            app.AccountUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Account

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AccountUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AccountUIFigure)
        end
    end
end