classdef CustomerAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CustomerAPPUIFigure        matlab.ui.Figure
        ChangePINLabel             matlab.ui.control.TextArea
        TransferLabel              matlab.ui.control.TextArea
        EnterAccountLabel          matlab.ui.control.Label
        EnterAccountTextArea       matlab.ui.control.TextArea
        ChangePINConfirmButton     matlab.ui.control.Button
        RepeatNewPINEditField      matlab.ui.control.NumericEditField
        RepeatNewPINLabel          matlab.ui.control.Label
        NewPINEditField            matlab.ui.control.NumericEditField
        NewPINLabel                matlab.ui.control.Label
        TransferConfirmButton      matlab.ui.control.Button
        TransferAmountyuanLabel    matlab.ui.control.Label
        TransferAmountEditField    matlab.ui.control.NumericEditField
        AccounttransferredtoLabel  matlab.ui.control.Label
        TransferEditField          matlab.ui.control.NumericEditField
        CheckBalanceLabel          matlab.ui.control.Label
        BalanceyuanLabel           matlab.ui.control.Label
        BalanceEditField           matlab.ui.control.NumericEditField
        TransactionLabel           matlab.ui.control.Label
        LogOutButton               matlab.ui.control.Button
        ChangePINButton            matlab.ui.control.Button
        QueryButton                matlab.ui.control.Button
        TransferButton             matlab.ui.control.Button
        LogInButton                matlab.ui.control.Button
        PINLabel                   matlab.ui.control.Label
        AccountLabel               matlab.ui.control.Label
        ReturnButton               matlab.ui.control.Button
        AccountEditField           matlab.ui.control.NumericEditField
        PINEditField               matlab.ui.control.NumericEditField
        WelcomeLabel               matlab.ui.control.Label
        ShanghaitechBankCustomerAPPLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        AccountDatabase;
        current_index = 0;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function InsertCardButtonPushed(app, event)
            
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            if app.CheckBalanceLabel.Visible == 1
                app.QueryButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.ChangePINButton.Visible = 1;
                app.LogOutButton.Visible = 1;
                
                app.BalanceyuanLabel.Visible = 0;
                app.BalanceEditField.Visible = 0;
              
                app.TransactionLabel.Visible = 1;
                app.CheckBalanceLabel.Visible = 0;
                
                app.ReturnButton.Visible = 0;
            elseif app.TransferLabel.Visible == 1
                app.QueryButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.ChangePINButton.Visible = 1;
                app.LogOutButton.Visible = 1;
                app.TransferConfirmButton.Visible = 0;
                app.TransferConfirmButton.Enable = 1;
                
                app.AccounttransferredtoLabel.Visible = 0;
                app.TransferEditField.Visible = 0;
                app.TransferEditField.Value = 0;
                app.TransferAmountEditField.Visible = 0;
                app.TransferAmountEditField.Value = 0;
                app.TransferAmountyuanLabel.Visible = 0;
              
                app.TransactionLabel.Visible = 1;
                app.TransferLabel.Visible = 0;
                
                app.ReturnButton.Visible = 0;
            elseif app.ChangePINLabel.Visible == 1
                app.QueryButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.ChangePINButton.Visible = 1;
                app.LogOutButton.Visible = 1;
                app.ChangePINConfirmButton.Visible = 0;
                
                app.NewPINEditField.Visible = 0;
                app.NewPINLabel.Visible = 0;
                app.RepeatNewPINEditField.Visible = 0;
                app.RepeatNewPINLabel.Visible = 0;
              
                app.TransactionLabel.Visible = 1;
                app.ChangePINLabel.Visible = 0;
                
                app.NewPINEditField.Value = 0;
                app.RepeatNewPINEditField.Value = 0;
                
                app.ReturnButton.Visible = 0;
            elseif app.AccountLabel.Visible == 1
                delete(app);
            end
        end

        % Button pushed function: LogInButton
        function LogInButtonPushed(app, event)
            index = app.AccountEditField.Value - 100000 + 1;
            if index >= 1 && index <= app.AccountDatabase.account_cnt
                if app.AccountDatabase.account_data{index, 1} == app.AccountEditField.Value + 1000000
                    app.EnterAccountTextArea.Value = "Your account has been cancelled!";
                    return;
                elseif app.AccountDatabase.account_data{index, 1} == app.AccountEditField.Value + 2000000
                    app.EnterAccountTextArea.Value = "Your account has been freezed!";
                    return
                end
                
                if app.AccountDatabase.account_data{index, 2} == app.PINEditField.Value
                    app.ReturnButton.Visible = 0;
                    app.LogInButton.Visible = 0;
                    app.QueryButton.Visible = 1;
                    app.TransferButton.Visible = 1;
                    app.ChangePINButton.Visible = 1;
                    app.LogOutButton.Visible = 1;
                    
                    app.AccountEditField.Visible = 0;
                    app.PINEditField.Visible = 0;
                    app.AccountLabel.Visible = 0;
                    app.PINLabel.Visible = 0;
                  
                    app.TransactionLabel.Visible = 1;
                    app.EnterAccountTextArea.Visible = 0;
                    app.EnterAccountLabel.Visible = 0;
                    
                    app.current_index = index;
                 else
                     app.EnterAccountTextArea.Value = "Wrong Password! Try again!";
                 end
             else
                app.EnterAccountTextArea.Value = "Wrong ID! Try again!";
            end
            

        end

        % Button pushed function: LogOutButton
        function LogOutButtonPushed(app, event)
            app.ReturnButton.Visible = 1;
            app.LogInButton.Visible = 1;
            app.QueryButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.ChangePINButton.Visible = 0;
            app.LogOutButton.Visible = 0;
            
            app.AccountEditField.Visible = 1;
            app.PINEditField.Visible = 1;
            app.AccountEditField.Value = 0;
            app.PINEditField.Value = 0;
            app.AccountLabel.Visible = 1;
            app.PINLabel.Visible = 1;
          
            app.TransactionLabel.Visible = 0;
            app.EnterAccountTextArea.Visible = 1;
            app.EnterAccountLabel.Visible = 1;
            
            app.EnterAccountTextArea.Value = 'Please enter your account and PIN to log in.';
        end

        % Button pushed function: QueryButton
        function QueryButtonPushed(app, event)
            app.QueryButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.ChangePINButton.Visible = 0;
            app.LogOutButton.Visible = 0;
            
            app.BalanceyuanLabel.Visible = 1;
            app.BalanceEditField.Visible = 1;
            app.BalanceEditField.Value = app.AccountDatabase.appQuery(app.current_index);
            
          
            app.TransactionLabel.Visible = 0;
            app.CheckBalanceLabel.Visible = 1;
            
            app.ReturnButton.Visible = 1;
        end

        % Button pushed function: TransferButton
        function TransferButtonPushed(app, event)
            app.QueryButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.ChangePINButton.Visible = 0;
            app.LogOutButton.Visible = 0;
            app.TransferConfirmButton.Visible = 1;
            
            app.AccounttransferredtoLabel.Visible = 1;
            app.TransferEditField.Visible = 1;
            app.TransferAmountEditField.Visible = 1;
            app.TransferAmountyuanLabel.Visible = 1;
          
            app.TransactionLabel.Visible = 0;
            app.TransferLabel.Visible = 1;
            app.TransferLabel.Value = 'Please fill in the account you want to transfer to and the amount.';
            
            app.ReturnButton.Visible = 1;
        end

        % Button pushed function: ChangePINButton
        function ChangePINButtonPushed(app, event)
            app.QueryButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.ChangePINButton.Visible = 0;
            app.LogOutButton.Visible = 0;
            app.ChangePINConfirmButton.Visible = 1;
            
            app.NewPINEditField.Visible = 1;
            app.NewPINLabel.Visible = 1;
            app.RepeatNewPINEditField.Visible = 1;
            app.RepeatNewPINLabel.Visible = 1;
          
            app.TransactionLabel.Visible = 0;
            app.ChangePINLabel.Visible = 1;
            
            app.ChangePINConfirmButton.Enable = 1;
            app.NewPINEditField.Editable = 1;
            app.RepeatNewPINEditField.Editable = 1;
            
            app.ChangePINLabel.Visible = 1;
            app.ChangePINLabel.Value = 'Please set your new 6-bit password.';
            
            app.ReturnButton.Visible = 1;
        end

        % Button pushed function: TransferConfirmButton
        function TransferConfirmButtonPushed(app, event)
            if app.TransferEditField.Value <= 99999 ||...
                    app.TransferEditField.Value > app.AccountDatabase.account_cnt - 1 + 100000 ||...
                    app.TransferEditField.Value == app.current_index + 100000 - 1
                app.TransferLabel.Value = 'Wrong account!';
            else
                if app.AccountDatabase.CheckAccount(app.TransferEditField.Value - 100000 + 1, app.TransferEditField.Value) == 0
                    app.TransferLabel.Value = 'The account has been freezed or cancelled!';
                    return;
                end
                
                if app.AccountDatabase.appQuery(app.current_index) < app.TransferAmountEditField.Value 
                    app.TransferLabel.Value = 'Not enough money!';
                elseif app.TransferAmountEditField.Value <= 0
                    app.TransferLabel.Value = 'The ammount should be positive!';
                else
                    app.TransferConfirmButton.Enable = 0;
                    app.AccountDatabase.appTransfer(app.current_index,app.TransferEditField.Value - 100000 + 1,app.TransferAmountEditField.Value);
                    app.AccountDatabase.Record(app.current_index + 100000 - 1, 'T', app.TransferAmountEditField.Value, app.TransferEditField.Value);
                    app.TransferLabel.Value = ['Transfer successfully!' ...
                        'If you want to transfer again, please return and continue.'];
                    
                end
            end
        end

        % Button pushed function: ChangePINConfirmButton
        function ChangePINConfirmButtonPushed(app, event)
            if app.NewPINEditField.Value > 99999 && app.NewPINEditField.Value < 1000000
                if app.NewPINEditField.Value == app.RepeatNewPINEditField.Value
                    app.ChangePINConfirmButton.Enable = 0;
                    app.AccountDatabase.changeAccountPIN(app.current_index,app.NewPINEditField.Value);
                    app.RepeatNewPINEditField.Editable = 0;
                    app.NewPINEditField.Editable = 0;
                    
                    app.ChangePINLabel.Value = 'Change sucessfully!';
                else
                    app.ChangePINLabel.Value = 'Check your new PIN again!';
                end
            else
                app.ChangePINLabel.Value = 'Your new PIN is not 6-bit. Try again!';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CustomerAPPUIFigure = uifigure('Visible', 'off');
            app.CustomerAPPUIFigure.Name = 'CustomerAPP';
            app.ShanghaitechBankCustomerAPPLabel = uilabel(app.CustomerAPPUIFigure);
            app.ShanghaitechBankCustomerAPPLabel.Text = 'Shanghaitech Bank Customer APP';
            app.WelcomeLabel = uilabel(app.CustomerAPPUIFigure);
            app.WelcomeLabel.Text = 'Welcome to Shanghaitech Bank!';
            app.PINEditField = uieditfield(app.CustomerAPPUIFigure, 'numeric');
            app.PINEditField.ValueDisplayFormat = '%.0f';
            app.AccountEditField = uieditfield(app.CustomerAPPUIFigure, 'numeric');
            app.AccountEditField.ValueDisplayFormat = '%.0f';
            app.ReturnButton = uibutton(app.CustomerAPPUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.AccountLabel = uilabel(app.CustomerAPPUIFigure);
            app.AccountLabel.Text = 'Account :';
            app.PINLabel = uilabel(app.CustomerAPPUIFigure);
            app.PINLabel.Text = 'PIN :';
            app.LogInButton = uibutton(app.CustomerAPPUIFigure, 'push');
            app.LogInButton.ButtonPushedFcn = createCallbackFcn(app, @LogInButtonPushed, true);
            app.LogInButton.Text = 'Log In';
            app.TransferButton = uibutton(app.CustomerAPPUIFigure, 'push');
            app.TransferButton.ButtonPushedFcn = createCallbackFcn(app, @TransferButtonPushed, true);
            app.TransferButton.Visible = 'off';
            app.TransferButton.Text = 'Transfer';
            app.QueryButton = uibutton(app.CustomerAPPUIFigure, 'push');
            app.QueryButton.ButtonPushedFcn = createCallbackFcn(app, @QueryButtonPushed, true);
            app.QueryButton.Visible = 'off';
            app.QueryButton.Text = 'Query';
            app.ChangePINButton = uibutton(app.CustomerAPPUIFigure, 'push');
            app.ChangePINButton.ButtonPushedFcn = createCallbackFcn(app, @ChangePINButtonPushed, true);
            app.ChangePINButton.Visible = 'off';
            app.ChangePINButton.Text = 'Change PIN';
            app.LogOutButton = uibutton(app.CustomerAPPUIFigure, 'push');
            app.LogOutButton.ButtonPushedFcn = createCallbackFcn(app, @LogOutButtonPushed, true);
            app.LogOutButton.Visible = 'off';
            app.LogOutButton.Text = 'Log Out';
            app.TransactionLabel = uilabel(app.CustomerAPPUIFigure);
            app.TransactionLabel.Visible = 'off';
            app.TransactionLabel.Text = 'Please select your transaction.';
            app.BalanceEditField = uieditfield(app.CustomerAPPUIFigure, 'numeric');
            app.BalanceEditField.ValueDisplayFormat = '%.2f';
            app.BalanceEditField.Editable = 'off';
            app.BalanceEditField.Visible = 'off';
            app.BalanceyuanLabel = uilabel(app.CustomerAPPUIFigure);
            app.BalanceyuanLabel.Visible = 'off';
            app.BalanceyuanLabel.Text = 'Balance (yuan) :';
            app.CheckBalanceLabel = uilabel(app.CustomerAPPUIFigure);
            app.CheckBalanceLabel.Visible = 'off';
            app.CheckBalanceLabel.Text = 'You can check your balance here.';
            app.TransferEditField = uieditfield(app.CustomerAPPUIFigure, 'numeric');
            app.TransferEditField.ValueDisplayFormat = '%.0f';
            app.TransferEditField.Visible = 'off';
            app.AccounttransferredtoLabel = uilabel(app.CustomerAPPUIFigure);
            app.AccounttransferredtoLabel.Visible = 'off';
            app.AccounttransferredtoLabel.Text = 'Account transferred to :';
            app.TransferAmountEditField = uieditfield(app.CustomerAPPUIFigure, 'numeric');
            app.TransferAmountEditField.ValueDisplayFormat = '%.2f';
            app.TransferAmountEditField.Visible = 'off';
            app.TransferAmountyuanLabel = uilabel(app.CustomerAPPUIFigure);
            app.TransferAmountyuanLabel.Visible = 'off';
            app.TransferAmountyuanLabel.Text = 'Amount (yuan) :';
            app.TransferConfirmButton = uibutton(app.CustomerAPPUIFigure, 'push');
            app.TransferConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @TransferConfirmButtonPushed, true);
            app.TransferConfirmButton.Visible = 'off';
            app.TransferConfirmButton.Text = 'Confirm';
            app.NewPINLabel = uilabel(app.CustomerAPPUIFigure);
            app.NewPINLabel.Visible = 'off';
            app.NewPINLabel.Text = 'New PIN :';
            app.NewPINEditField = uieditfield(app.CustomerAPPUIFigure, 'numeric');
            app.NewPINEditField.ValueDisplayFormat = '%.0f';
            app.NewPINEditField.Visible = 'off';
            app.RepeatNewPINLabel = uilabel(app.CustomerAPPUIFigure);
            app.RepeatNewPINLabel.Visible = 'off';
            app.RepeatNewPINLabel.Text = 'Repeat New PIN :';
            app.RepeatNewPINEditField = uieditfield(app.CustomerAPPUIFigure, 'numeric');
            app.RepeatNewPINEditField.ValueDisplayFormat = '%.0f';
            app.RepeatNewPINEditField.Visible = 'off';
            app.ChangePINConfirmButton = uibutton(app.CustomerAPPUIFigure, 'push');
            app.ChangePINConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ChangePINConfirmButtonPushed, true);
            app.ChangePINConfirmButton.Visible = 'off';
            app.ChangePINConfirmButton.Text = 'Confirm';
            app.EnterAccountTextArea = uitextarea(app.CustomerAPPUIFigure);
            app.EnterAccountTextArea.Editable = 'off';
            app.EnterAccountTextArea.Value = {'Please enter your account and PIN.'};
            app.EnterAccountLabel = uilabel(app.CustomerAPPUIFigure);
            app.EnterAccountLabel.Text = 'Please enter your account and PIN.';
            app.TransferLabel = uitextarea(app.CustomerAPPUIFigure);
            app.TransferLabel.Editable = 'off';
            app.TransferLabel.Visible = 'off';
            app.TransferLabel.Value = {'Please fill in the account you want to transfer to and the amount.'};
            app.ChangePINLabel = uitextarea(app.CustomerAPPUIFigure);
            app.ChangePINLabel.Editable = 'off';
            app.ChangePINLabel.Visible = 'off';
            app.ChangePINLabel.Value = {'Please enter your new PIN and repeat it.'};
            % Show the figure after all components are created
            app.CustomerAPPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CustomerAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CustomerAPPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CustomerAPPUIFigure)
        end
    end
end