classdef Records < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RecordsUIFigure   matlab.ui.Figure
        OperationLabel    matlab.ui.control.Label
        Query2Button      matlab.ui.control.Button
        DropDown          matlab.ui.control.DropDown
        QueryButton       matlab.ui.control.Button
        AccountLabel      matlab.ui.control.Label
        AccountEditField  matlab.ui.control.NumericEditField
        ReturnButton      matlab.ui.control.Button
        RefreshButton     matlab.ui.control.Button
        UITable           matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            records_data = readcell('records_data.xlsx','Range','A1:D1000');
            app.UITable.Data = records_data;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            records_data = readcell('records_data.xlsx','Range','A1:D1000');
            app.UITable.Data = records_data;
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: QueryButton
        function QueryButtonPushed(app, event)
            records_data = readcell('records_data.xlsx','Range','A1:D1000');
            records = cell(1000,4);
            cnt = 1;
            for i = 1: 1000
                if records_data{i,1} == app.AccountEditField.Value
                    records(cnt,:) = records_data(i,:);
                    cnt = cnt + 1;
                end
            end
            app.UITable.Data = records;
        end

        % Button pushed function: Query2Button
        function Query2ButtonPushed(app, event)
            records_data = readcell('records_data.xlsx','Range','A1:D1000');
            records = cell(1000,4);
            cnt = 1;
            for i = 1: 1000
                if records_data{i,2} == app.DropDown.Value(1)
                    records(cnt,:) = records_data(i,:);
                    cnt = cnt + 1;
                end
            end
            app.UITable.Data = records;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RecordsUIFigure = uifigure('Visible', 'off');
            app.RecordsUIFigure.Name = 'Records';
            app.UITable = uitable(app.RecordsUIFigure);
            app.UITable.ColumnName = {'Account'; 'Operation'; 'Amount'; 'Account1'};
            app.UITable.RowName = {};
            app.RefreshButton = uibutton(app.RecordsUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ReturnButton = uibutton(app.RecordsUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.AccountEditField = uieditfield(app.RecordsUIFigure, 'numeric');
            app.AccountEditField.ValueDisplayFormat = '%.0f';
            app.AccountLabel = uilabel(app.RecordsUIFigure);
            app.AccountLabel.Text = 'Account: ';
            app.QueryButton = uibutton(app.RecordsUIFigure, 'push');
            app.QueryButton.ButtonPushedFcn = createCallbackFcn(app, @QueryButtonPushed, true);
            app.QueryButton.Text = 'Query';
            app.DropDown = uidropdown(app.RecordsUIFigure);
            app.DropDown.Items = {'Deposit', 'Withdraw', 'Transfer'};
            app.DropDown.Value = 'Deposit';
            app.Query2Button = uibutton(app.RecordsUIFigure, 'push');
            app.Query2Button.ButtonPushedFcn = createCallbackFcn(app, @Query2ButtonPushed, true);
            app.Query2Button.Text = 'Query';
            app.OperationLabel = uilabel(app.RecordsUIFigure);
            app.OperationLabel.Text = 'Operation:';
            % Show the figure after all components are created
            app.RecordsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Records

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RecordsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RecordsUIFigure)
        end
    end
end