classdef StaffAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StaffAPPUIFigure               matlab.ui.Figure
        CancelAccountButton            matlab.ui.control.Button
        RecordsButton                  matlab.ui.control.Button
        RegUserNameEditField           matlab.ui.control.EditField
        ChangePasswordTextArea         matlab.ui.control.TextArea
        ChangePasswordConfirmButton    matlab.ui.control.Button
        RepeatNewPasswordEditField     matlab.ui.control.NumericEditField
        RepeatNewPasswordLabel         matlab.ui.control.Label
        NewPasswordEditField           matlab.ui.control.NumericEditField
        NewPasswordLabel               matlab.ui.control.Label
        CreateTextArea                 matlab.ui.control.TextArea
        CreateConfirmButton            matlab.ui.control.Button
        CreateRepeatPINEditField       matlab.ui.control.NumericEditField
        CreateRepeatPINLabel           matlab.ui.control.Label
        CreatePINEditField             matlab.ui.control.NumericEditField
        CreatePINLabel                 matlab.ui.control.Label
        CreateAccountIDEditField       matlab.ui.control.NumericEditField
        CreateAccountLabel             matlab.ui.control.Label
        ManageAccountButton            matlab.ui.control.Button
        ChangePasswordButton           matlab.ui.control.Button
        LogOutButton                   matlab.ui.control.Button
        CreateAccountButton            matlab.ui.control.Button
        RegRepeatPasswordEditField     matlab.ui.control.NumericEditField
        RegPasswordLabel               matlab.ui.control.Label
        RegTextArea                    matlab.ui.control.TextArea
        RegRegisterButton              matlab.ui.control.Button
        RegUserNameLabel               matlab.ui.control.Label
        RegRepeatPasswordLabel         matlab.ui.control.Label
        RegPasswordEditField           matlab.ui.control.NumericEditField
        RegUserIDEditField             matlab.ui.control.NumericEditField
        RegUserIDLabel                 matlab.ui.control.Label
        ShanghaitechBankStaffAPPLabel  matlab.ui.control.Label
        LogPasswordEditField           matlab.ui.control.NumericEditField
        LogUserIDEditField             matlab.ui.control.NumericEditField
        ReturnButton                   matlab.ui.control.Button
        LogUserIDLabel                 matlab.ui.control.Label
        LogPasswordLabel               matlab.ui.control.Label
        LogInButton                    matlab.ui.control.Button
        LogRegisterButton              matlab.ui.control.Button
        LogTextArea                    matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        BankDatabase % Description
        acc             Account
        rec             Records
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LogRegisterButton
        function LogRegisterButtonPushed(app, event)
            app.LogUserIDEditField.Visible = 0;
            app.LogUserIDLabel.Visible = 0;
            app.LogPasswordEditField.Visible = 0;
            app.LogPasswordLabel.Visible = 0;
            app.RegUserNameEditField.Visible = 1;
            app.RegUserNameLabel.Visible = 1;
            app.RegUserIDEditField.Visible = 1;
            app.RegUserIDEditField.Value = app.BankDatabase.createUser();
            app.RegUserIDLabel.Visible = 1;
            app.RegPasswordEditField.Visible = 1;
            app.RegPasswordLabel.Visible = 1;
            app.RegRepeatPasswordEditField.Visible = 1;
            app.RegRepeatPasswordLabel.Visible = 1;
            
            app.LogRegisterButton.Visible = 0;
            app.LogInButton.Visible = 0;
            app.RegRegisterButton.Visible = 1;
            
            app.LogTextArea.Visible = 0;
            app.RegTextArea.Visible = 1;
            
            app.RegRegisterButton.Enable = 1;
            app.RegUserNameEditField.Editable = 1;
            app.RegPasswordEditField.Editable = 1;
            app.RegRepeatPasswordEditField.Editable = 1;
            
            app.RegTextArea.Value = 'Please set your 6-bit password.';
            app.ReturnButton.Visible = 1;
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            if app.RegUserNameEditField.Visible == 1
                app.LogUserIDEditField.Visible = 1;
                app.LogUserIDEditField.Value = 0;
                app.LogUserIDLabel.Visible = 1;
                app.LogPasswordEditField.Visible = 1;
                app.LogPasswordEditField.Value = 0;
                app.LogPasswordLabel.Visible = 1;
                app.RegUserNameEditField.Visible = 0;
                app.RegUserNameLabel.Visible = 0;
                app.RegUserIDEditField.Visible = 0;
                app.RegUserIDLabel.Visible = 0;
                app.RegPasswordEditField.Visible = 0;
                app.RegPasswordLabel.Visible = 0;
                app.RegRepeatPasswordEditField.Visible = 0;
                app.RegRepeatPasswordLabel.Visible = 0;
                
                app.LogRegisterButton.Visible = 1;
                app.LogInButton.Visible = 1;
                app.RegRegisterButton.Visible = 0;
                
                app.LogTextArea.Visible = 1;
                app.LogTextArea.Value = 'Please enter your ID and password to log in.';
                app.RegTextArea.Visible = 0;
                app.ReturnButton.Visible = 0;
                
                app.RegPasswordEditField.Value = 0;
                app.RegRepeatPasswordEditField.Value = 0;
                app.RegUserNameEditField.Value = '';
            elseif app.CreateAccountLabel.Visible == 1
                app.CreateAccountButton.Visible = 1;
                %app.CancelAccountButton.Visible = 1;
                app.RecordsButton.Visible = 1;
                app.ManageAccountButton.Visible = 1;
                app.ChangePasswordButton.Visible = 1;
                app.LogOutButton.Visible = 1;
                app.CreateAccountIDEditField.Visible = 0;
                app.CreateAccountLabel.Visible = 0;
                app.CreatePINEditField.Visible = 0;
                app.CreatePINLabel.Visible = 0;
                app.CreateRepeatPINEditField.Visible = 0;
                app.CreateRepeatPINLabel.Visible = 0;
                
                app.CreateConfirmButton.Visible = 0;
                
                app.CreateTextArea.Visible = 0;
                app.CreateTextArea.Value = 'Please set your 6-bit PIN.';
                app.ReturnButton.Visible = 0;
                
                app.CreatePINEditField.Value = 0;
                app.CreateRepeatPINEditField.Value = 0;
            elseif app.NewPasswordLabel.Visible == 1
                app.CreateAccountButton.Visible = 1;
                %app.CancelAccountButton.Visible = 1;
                app.RecordsButton.Visible = 1;
                app.ManageAccountButton.Visible = 1;
                app.ChangePasswordButton.Visible = 1;
                app.LogOutButton.Visible = 1;
                app.NewPasswordEditField.Visible = 0;
                app.NewPasswordLabel.Visible = 0;
                app.RepeatNewPasswordEditField.Visible = 0;
                app.RepeatNewPasswordLabel.Visible = 0;
                
                app.ChangePasswordConfirmButton.Visible = 0;
                
                app.ChangePasswordTextArea.Visible = 0;
                app.ReturnButton.Visible = 0;
                
                app.NewPasswordEditField.Value = 0;
                app.RepeatNewPasswordEditField.Value = 0;
            end           
        end

        % Button pushed function: LogInButton
        function LogInButtonPushed(app, event)
             index = app.LogUserIDEditField.Value - 10000 + 1;
             if index >= 1 && index <= app.BankDatabase.user_cnt
                 if app.BankDatabase.user_data{index,3} == app.LogPasswordEditField.Value
                    app.LogUserIDEditField.Visible = 0;
                    app.LogUserIDLabel.Visible = 0;
                    app.LogPasswordEditField.Visible = 0;
                    app.LogPasswordLabel.Visible = 0;
                    app.CreateAccountButton.Visible = 1;
                    %app.CancelAccountButton.Visible = 1;
                    app.RecordsButton.Visible = 1;
                    app.ManageAccountButton.Visible = 1;
                    app.ChangePasswordButton.Visible = 1;
                    app.LogOutButton.Visible = 1;
                                
                    app.LogRegisterButton.Visible = 0;
                    app.LogInButton.Visible = 0;
                    
                    app.LogTextArea.Visible = 0;
                    
                    app.BankDatabase.current_index1 = index;
                 else
                     app.LogTextArea.Value = "Wrong Password! Try again!";
                 end
             else
                app.LogTextArea.Value = "Wrong ID! Try again!";
             end
                
        end

        % Button pushed function: CreateAccountButton
        function CreateAccountButtonPushed(app, event)
            app.CreateAccountButton.Visible = 0;
            %app.CancelAccountButton.Visible = 0;
            app.RecordsButton.Visible = 0;
            app.ManageAccountButton.Visible = 0;
            app.ChangePasswordButton.Visible = 0;
            app.LogOutButton.Visible = 0;
            app.CreateAccountIDEditField.Visible = 1;
            app.CreateAccountIDEditField.Value = app.BankDatabase.createAccount();
            app.CreateAccountLabel.Visible = 1;
            app.CreatePINEditField.Visible = 1;
            app.CreatePINLabel.Visible = 1;
            app.CreateRepeatPINEditField.Visible = 1;
            app.CreateRepeatPINLabel.Visible = 1;
            
            app.CreateConfirmButton.Visible = 1;
            
            app.CreateConfirmButton.Enable = 1;
            app.CreatePINEditField.Editable = 1;
            app.CreateRepeatPINEditField.Editable = 1;
            
            app.CreateTextArea.Visible = 1;
            app.CreateTextArea.Value = 'Please set your 6-bit PIN.';
            app.ReturnButton.Visible = 1;
            
            app.BankDatabase.account_data = readcell('account_data.xlsx','Range','A1:C1000');
            cell1 = readcell('account_data.xlsx','Range','D1');
            app.BankDatabase.account_cnt = cell1{1};
        end

        % Button pushed function: LogOutButton
        function LogOutButtonPushed(app, event)
            app.LogUserIDEditField.Visible = 1;
            app.LogUserIDEditField.Value = 0;
            app.LogUserIDLabel.Visible = 1;
            app.LogPasswordEditField.Visible = 1;
            app.LogPasswordEditField.Visible = 1;
            app.LogPasswordEditField.Value = 0;
            app.LogPasswordLabel.Visible = 1;
            app.CreateAccountButton.Visible = 0;
            %app.CancelAccountButton.Visible = 0;
            app.RecordsButton.Visible = 0;
            app.ManageAccountButton.Visible = 0;
            app.ChangePasswordButton.Visible = 0;
            app.LogOutButton.Visible = 0;
                        
            app.LogRegisterButton.Visible = 1;
            app.LogInButton.Visible = 1;
            
            app.LogTextArea.Visible = 1;
            app.LogTextArea.Value = 'Please enter your ID and password to log in.';
        end

        % Button pushed function: ChangePasswordButton
        function ChangePasswordButtonPushed(app, event)
            app.CreateAccountButton.Visible = 0;
            %app.CancelAccountButton.Visible = 0;
            app.RecordsButton.Visible = 0;
            app.ManageAccountButton.Visible = 0;
            app.ChangePasswordButton.Visible = 0;
            app.LogOutButton.Visible = 0;
            app.NewPasswordEditField.Visible = 1;
            app.NewPasswordLabel.Visible = 1;
            app.RepeatNewPasswordEditField.Visible = 1;
            app.RepeatNewPasswordLabel.Visible = 1;
            
            app.ChangePasswordConfirmButton.Visible = 1;
            
            app.ChangePasswordConfirmButton.Enable = 1;
            app.NewPasswordEditField.Editable = 1;
            app.RepeatNewPasswordEditField.Editable = 1;
            
            app.ChangePasswordTextArea.Visible = 1;
            app.ChangePasswordTextArea.Value = 'Please set your new 6-bit password.';
            app.ReturnButton.Visible = 1;
        end

        % Button pushed function: CreateConfirmButton
        function CreateConfirmButtonPushed(app, event)
            if app.CreatePINEditField.Value > 99999 && app.CreatePINEditField.Value < 1000000
                if app.CreatePINEditField.Value == app.CreateRepeatPINEditField.Value
                    app.CreateConfirmButton.Enable = 0;
                    data = {app.CreateAccountIDEditField.Value, app.CreatePINEditField.Value, 0.00};
                    app.BankDatabase.addAccount(data);
                    app.CreatePINEditField.Editable = 0;
                    app.CreateRepeatPINEditField.Editable = 0;
                    
                    app.CreateTextArea.Value = 'Register sucessfully! Return to manage your account.';
                else
                    app.CreateTextArea.Value = 'Check your password again!';
                end
            else
                app.CreateTextArea.Value = 'Your password is not 6-bit. Try again!';
            end
            
        end

        % Button pushed function: RegRegisterButton
        function RegRegisterButtonPushed(app, event)
            if app.RegPasswordEditField.Value > 99999 && app.RegPasswordEditField.Value < 1000000
                if app.RegPasswordEditField.Value == app.RegRepeatPasswordEditField.Value
                    app.RegRegisterButton.Enable = 0;
                    data = {app.RegUserNameEditField.Value, app.RegUserIDEditField.Value, app.RegPasswordEditField.Value};
                    app.BankDatabase.addUser(data);
                    
                    app.RegUserNameEditField.Editable = 0;
                    app.RegPasswordEditField.Editable = 0;
                    app.RegRepeatPasswordEditField.Editable = 0;
                    app.RegTextArea.Value = 'Register sucessfully! Return to log in.';
                else
                    app.RegTextArea.Value = 'Check your password again!';
                end
            else
                app.RegTextArea.Value = 'Your password is not 6-bit. Try again!';
            end
        end

        % Button pushed function: ChangePasswordConfirmButton
        function ChangePasswordConfirmButtonPushed(app, event)
            if app.NewPasswordEditField.Value > 99999 && app.NewPasswordEditField.Value < 1000000
                if app.NewPasswordEditField.Value == app.RepeatNewPasswordEditField.Value
                    app.ChangePasswordConfirmButton.Enable = 0;
                    app.BankDatabase.changeUserpassword(app.NewPasswordEditField.Value);
                    app.RepeatNewPasswordEditField.Editable = 0;
                    app.NewPasswordEditField.Editable = 0;
                    
                    app.ChangePasswordTextArea.Value = 'Change sucessfully!';
                else
                    app.ChangePasswordTextArea.Value = 'Check your new password again!';
                end
            else
                app.ChangePasswordTextArea.Value = 'Your new password is not 6-bit. Try again!';
            end
        end

        % Button pushed function: ManageAccountButton
        function ManageAccountButtonPushed(app, event)
            app.acc = Account;
            app.acc.AccountDatabase = app.BankDatabase;
        end

        % Button pushed function: RecordsButton
        function RecordsButtonPushed(app, event)
            app.rec = Records;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StaffAPPUIFigure = uifigure('Visible', 'off');
            app.StaffAPPUIFigure.Name = 'StaffAPP';
            app.LogTextArea = uitextarea(app.StaffAPPUIFigure);
            app.LogTextArea.Editable = 'off';
            app.LogTextArea.Value = {'Please enter your ID and password to log in.'};
            app.LogRegisterButton = uibutton(app.StaffAPPUIFigure, 'push');
            app.LogRegisterButton.ButtonPushedFcn = createCallbackFcn(app, @LogRegisterButtonPushed, true);
            app.LogRegisterButton.Text = 'Register';
            app.LogInButton = uibutton(app.StaffAPPUIFigure, 'push');
            app.LogInButton.ButtonPushedFcn = createCallbackFcn(app, @LogInButtonPushed, true);
            app.LogInButton.Text = 'Log In';
            app.LogPasswordLabel = uilabel(app.StaffAPPUIFigure);
            app.LogPasswordLabel.Text = 'Password :';
            app.LogUserIDLabel = uilabel(app.StaffAPPUIFigure);
            app.LogUserIDLabel.Text = 'User ID :';
            app.ReturnButton = uibutton(app.StaffAPPUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Visible = 'off';
            app.ReturnButton.Text = 'Return';
            app.LogUserIDEditField = uieditfield(app.StaffAPPUIFigure, 'numeric');
            app.LogUserIDEditField.ValueDisplayFormat = '%.0f';
            app.LogPasswordEditField = uieditfield(app.StaffAPPUIFigure, 'numeric');
            app.LogPasswordEditField.ValueDisplayFormat = '%.0f';
            app.ShanghaitechBankStaffAPPLabel = uilabel(app.StaffAPPUIFigure);
            app.ShanghaitechBankStaffAPPLabel.Text = 'Shanghaitech Bank Staff APP';
            app.RegUserIDLabel = uilabel(app.StaffAPPUIFigure);
            app.RegUserIDLabel.Visible = 'off';
            app.RegUserIDLabel.Text = 'User ID :';
            app.RegUserIDEditField = uieditfield(app.StaffAPPUIFigure, 'numeric');
            app.RegUserIDEditField.ValueDisplayFormat = '%.0f';
            app.RegUserIDEditField.Editable = 'off';
            app.RegUserIDEditField.Visible = 'off';
            app.RegPasswordEditField = uieditfield(app.StaffAPPUIFigure, 'numeric');
            app.RegPasswordEditField.ValueDisplayFormat = '%.0f';
            app.RegPasswordEditField.Visible = 'off';
            app.RegRepeatPasswordLabel = uilabel(app.StaffAPPUIFigure);
            app.RegRepeatPasswordLabel.Visible = 'off';
            app.RegRepeatPasswordLabel.Text = 'Repeat Password :';
            app.RegUserNameLabel = uilabel(app.StaffAPPUIFigure);
            app.RegUserNameLabel.Visible = 'off';
            app.RegUserNameLabel.Text = 'User Name :';
            app.RegRegisterButton = uibutton(app.StaffAPPUIFigure, 'push');
            app.RegRegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegRegisterButtonPushed, true);
            app.RegRegisterButton.Visible = 'off';
            app.RegRegisterButton.Text = 'Register now!';
            app.RegTextArea = uitextarea(app.StaffAPPUIFigure);
            app.RegTextArea.Editable = 'off';
            app.RegTextArea.Visible = 'off';
            app.RegPasswordLabel = uilabel(app.StaffAPPUIFigure);
            app.RegPasswordLabel.Visible = 'off';
            app.RegPasswordLabel.Text = 'Password :';
            app.RegRepeatPasswordEditField = uieditfield(app.StaffAPPUIFigure, 'numeric');
            app.RegRepeatPasswordEditField.ValueDisplayFormat = '%.0f';
            app.RegRepeatPasswordEditField.Visible = 'off';
            app.CreateAccountButton = uibutton(app.StaffAPPUIFigure, 'push');
            app.CreateAccountButton.ButtonPushedFcn = createCallbackFcn(app, @CreateAccountButtonPushed, true);
            app.CreateAccountButton.Visible = 'off';
            app.CreateAccountButton.Text = 'Create Account';
            app.LogOutButton = uibutton(app.StaffAPPUIFigure, 'push');
            app.LogOutButton.ButtonPushedFcn = createCallbackFcn(app, @LogOutButtonPushed, true);
            app.LogOutButton.Visible = 'off';
            app.LogOutButton.Text = 'Log Out';
            app.ChangePasswordButton = uibutton(app.StaffAPPUIFigure, 'push');
            app.ChangePasswordButton.ButtonPushedFcn = createCallbackFcn(app, @ChangePasswordButtonPushed, true);
            app.ChangePasswordButton.Visible = 'off';
            app.ChangePasswordButton.Text = 'Change Password';
            app.ManageAccountButton = uibutton(app.StaffAPPUIFigure, 'push');
            app.ManageAccountButton.ButtonPushedFcn = createCallbackFcn(app, @ManageAccountButtonPushed, true);
            app.ManageAccountButton.Visible = 'off';
            app.ManageAccountButton.Text = 'Manage Account';
            app.CreateAccountLabel = uilabel(app.StaffAPPUIFigure);
            app.CreateAccountLabel.Visible = 'off';
            app.CreateAccountLabel.Text = 'Account :';
            app.CreateAccountIDEditField = uieditfield(app.StaffAPPUIFigure, 'numeric');
            app.CreateAccountIDEditField.ValueDisplayFormat = '%.0f';
            app.CreateAccountIDEditField.Editable = 'off';
            app.CreateAccountIDEditField.Visible = 'off';
            app.CreatePINLabel = uilabel(app.StaffAPPUIFigure);
            app.CreatePINLabel.Visible = 'off';
            app.CreatePINLabel.Text = 'PIN :';
            app.CreatePINEditField = uieditfield(app.StaffAPPUIFigure, 'numeric');
            app.CreatePINEditField.ValueDisplayFormat = '%.0f';
            app.CreatePINEditField.Visible = 'off';
            app.CreateRepeatPINLabel = uilabel(app.StaffAPPUIFigure);
            app.CreateRepeatPINLabel.Visible = 'off';
            app.CreateRepeatPINLabel.Text = 'Repeat PIN :';
            app.CreateRepeatPINEditField = uieditfield(app.StaffAPPUIFigure, 'numeric');
            app.CreateRepeatPINEditField.ValueDisplayFormat = '%.0f';
            app.CreateRepeatPINEditField.Visible = 'off';
            app.CreateConfirmButton = uibutton(app.StaffAPPUIFigure, 'push');
            app.CreateConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @CreateConfirmButtonPushed, true);
            app.CreateConfirmButton.Visible = 'off';
            app.CreateConfirmButton.Text = 'Confirm';
            app.CreateTextArea = uitextarea(app.StaffAPPUIFigure);
            app.CreateTextArea.Editable = 'off';
            app.CreateTextArea.Visible = 'off';
            app.NewPasswordLabel = uilabel(app.StaffAPPUIFigure);
            app.NewPasswordLabel.Visible = 'off';
            app.NewPasswordLabel.Text = 'New Password :';
            app.NewPasswordEditField = uieditfield(app.StaffAPPUIFigure, 'numeric');
            app.NewPasswordEditField.ValueDisplayFormat = '%.0f';
            app.NewPasswordEditField.Visible = 'off';
            app.RepeatNewPasswordLabel = uilabel(app.StaffAPPUIFigure);
            app.RepeatNewPasswordLabel.Visible = 'off';
            app.RepeatNewPasswordLabel.Text = 'Repeat New Password :';
            app.RepeatNewPasswordEditField = uieditfield(app.StaffAPPUIFigure, 'numeric');
            app.RepeatNewPasswordEditField.ValueDisplayFormat = '%.0f';
            app.RepeatNewPasswordEditField.Visible = 'off';
            app.ChangePasswordConfirmButton = uibutton(app.StaffAPPUIFigure, 'push');
            app.ChangePasswordConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ChangePasswordConfirmButtonPushed, true);
            app.ChangePasswordConfirmButton.Visible = 'off';
            app.ChangePasswordConfirmButton.Text = 'Confirm';
            app.ChangePasswordTextArea = uitextarea(app.StaffAPPUIFigure);
            app.ChangePasswordTextArea.Editable = 'off';
            app.ChangePasswordTextArea.Visible = 'off';
            app.RegUserNameEditField = uieditfield(app.StaffAPPUIFigure, 'text');
            app.RegUserNameEditField.Visible = 'off';
            app.RecordsButton = uibutton(app.StaffAPPUIFigure, 'push');
            app.RecordsButton.ButtonPushedFcn = createCallbackFcn(app, @RecordsButtonPushed, true);
            app.RecordsButton.Visible = 'off';
            app.RecordsButton.Text = 'Records';
            app.CancelAccountButton = uibutton(app.StaffAPPUIFigure, 'push');
            app.CancelAccountButton.Visible = 'off';
            app.CancelAccountButton.Text = 'Cancel / Freeze';
            % Show the figure after all components are created
            app.StaffAPPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StaffAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StaffAPPUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StaffAPPUIFigure)
        end
    end
end