classdef GameBoard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        ConfirmCancel   matlab.ui.control.Button
        ConfirmConfirm  matlab.ui.control.Button
        ConfirmMessage  matlab.ui.control.Label
        ConfirmWindow   matlab.ui.control.Image
        choiceBox       matlab.ui.control.Image
        DisplayTime     matlab.ui.control.Label
        pos_9_10        matlab.ui.control.Image
        pos_9_9         matlab.ui.control.Image
        pos_9_8         matlab.ui.control.Image
        pos_9_7         matlab.ui.control.Image
        pos_9_6         matlab.ui.control.Image
        pos_9_5         matlab.ui.control.Image
        pos_9_4         matlab.ui.control.Image
        pos_9_3         matlab.ui.control.Image
        pos_9_2         matlab.ui.control.Image
        pos_9_1         matlab.ui.control.Image
        pos_8_10        matlab.ui.control.Image
        pos_8_9         matlab.ui.control.Image
        pos_8_8         matlab.ui.control.Image
        pos_8_7         matlab.ui.control.Image
        pos_8_6         matlab.ui.control.Image
        pos_8_5         matlab.ui.control.Image
        pos_8_4         matlab.ui.control.Image
        pos_8_3         matlab.ui.control.Image
        pos_8_2         matlab.ui.control.Image
        pos_8_1         matlab.ui.control.Image
        pos_7_10        matlab.ui.control.Image
        pos_7_9         matlab.ui.control.Image
        pos_7_8         matlab.ui.control.Image
        pos_7_7         matlab.ui.control.Image
        pos_7_6         matlab.ui.control.Image
        pos_7_5         matlab.ui.control.Image
        pos_7_4         matlab.ui.control.Image
        pos_7_3         matlab.ui.control.Image
        pos_7_2         matlab.ui.control.Image
        pos_7_1         matlab.ui.control.Image
        pos_6_10        matlab.ui.control.Image
        pos_6_9         matlab.ui.control.Image
        pos_6_8         matlab.ui.control.Image
        pos_6_7         matlab.ui.control.Image
        pos_6_6         matlab.ui.control.Image
        pos_6_5         matlab.ui.control.Image
        pos_6_4         matlab.ui.control.Image
        pos_6_3         matlab.ui.control.Image
        pos_6_2         matlab.ui.control.Image
        pos_6_1         matlab.ui.control.Image
        pos_5_10        matlab.ui.control.Image
        pos_5_9         matlab.ui.control.Image
        pos_5_8         matlab.ui.control.Image
        pos_5_7         matlab.ui.control.Image
        pos_5_6         matlab.ui.control.Image
        pos_5_5         matlab.ui.control.Image
        pos_5_4         matlab.ui.control.Image
        pos_5_3         matlab.ui.control.Image
        pos_5_2         matlab.ui.control.Image
        pos_5_1         matlab.ui.control.Image
        pos_4_10        matlab.ui.control.Image
        pos_4_9         matlab.ui.control.Image
        pos_4_8         matlab.ui.control.Image
        pos_4_7         matlab.ui.control.Image
        pos_4_6         matlab.ui.control.Image
        pos_4_5         matlab.ui.control.Image
        pos_4_4         matlab.ui.control.Image
        pos_4_3         matlab.ui.control.Image
        pos_4_2         matlab.ui.control.Image
        pos_4_1         matlab.ui.control.Image
        pos_3_10        matlab.ui.control.Image
        pos_3_9         matlab.ui.control.Image
        pos_3_8         matlab.ui.control.Image
        pos_3_7         matlab.ui.control.Image
        pos_3_6         matlab.ui.control.Image
        pos_3_5         matlab.ui.control.Image
        pos_3_4         matlab.ui.control.Image
        pos_3_3         matlab.ui.control.Image
        pos_3_2         matlab.ui.control.Image
        pos_3_1         matlab.ui.control.Image
        pos_2_10        matlab.ui.control.Image
        pos_2_9         matlab.ui.control.Image
        pos_2_8         matlab.ui.control.Image
        pos_2_7         matlab.ui.control.Image
        pos_2_6         matlab.ui.control.Image
        pos_2_5         matlab.ui.control.Image
        pos_2_4         matlab.ui.control.Image
        pos_2_3         matlab.ui.control.Image
        pos_2_2         matlab.ui.control.Image
        pos_2_1         matlab.ui.control.Image
        pos_1_10        matlab.ui.control.Image
        pos_1_9         matlab.ui.control.Image
        pos_1_8         matlab.ui.control.Image
        pos_1_7         matlab.ui.control.Image
        pos_1_6         matlab.ui.control.Image
        pos_1_5         matlab.ui.control.Image
        pos_1_4         matlab.ui.control.Image
        pos_1_3         matlab.ui.control.Image
        pos_1_2         matlab.ui.control.Image
        pos_1_1         matlab.ui.control.Image
        DrawRequest     matlab.ui.control.Button
        DefeatRequest   matlab.ui.control.Button
        RegretRequest   matlab.ui.control.Button
        Background      matlab.ui.control.Image
    end

    
    properties (Access = private)
        Direction           % Red / Black
        status              % See doc
        ViewModel           UIViewModel
        currentChoice       struct
        idx1                int16
        idx2                int16
        AllowClickEmpty     logical
        localUpdateTiming   timer
        finished            logical
        regret              logical         % regret possible light up
    end

    properties (Access = public)
        deadline            int64           % The time of move
    end
    
    methods (Access = public)
        
        function UpdateBoard(app, board)
            app.regret = true;
            switch app.Direction
                case "Red"
                    for i = 1:9
                        for j = 1:10
                            name = "pos_" + int2str(i) + "_" + int2str(j);
                            app.(matlab.lang.makeValidName(name)).ImageSource = "imgs/piece_" + int2str(board(i, j)) + ".png";
                        end
                    end
                case "Black"
                    for i = 1:9
                        for j = 1:10
                            name = "pos_" + int2str(10 - i) + "_" + int2str(11 - j);
                            app.(matlab.lang.makeValidName(name)).ImageSource = "imgs/piece_" + int2str(board(i, j)) + ".png";
                        end
                    end
            end

        end
        
        function results = notify(app, code) % Send message here to finish a match
            if code == 400          % Disconnect
                app.status = 400;
                if app.ConfirmMessage.Text == "对方已认输" || app.ConfirmMessage.Text == "胜利" || app.ConfirmMessage.Text == "您已认输" || app.ConfirmMessage.Text == "失败" || app.ConfirmMessage.Text == "平局"
                    return
                end
                app.ConfirmMessage.Text = "已断开连接";
                app.ConfirmConfirm.Text = "返回";
                app.ConfirmWindow.Visible = "on";
                app.ConfirmMessage.Visible = "on";
                app.ConfirmConfirm.Visible = "on";
                app.ConfirmCancel.Visible = "off";
                return
            end
            if code == 201
                win(app, 1);
                results = 1;        % 1 stands for agreed
                return;
            end
            if app.status ~= 0 && (app.status == 200 || app.status == 202)
                results = 0;        % 0 stands for busy / refuse
                return;
            else
                app.status = code;
                switch app.status
                    case 200
                        % Draw request
                        app.ConfirmMessage.Text = "对方提出了求和";
                        app.ConfirmConfirm.Text = "同意";
                        app.ConfirmCancel.Text = "拒绝";
                        app.ConfirmWindow.Visible = "on";
                        app.ConfirmMessage.Visible = "on";
                        app.ConfirmConfirm.Visible = "on";
                        app.ConfirmCancel.Visible = "on";
                        results = 0;
                        return;
                    case 202
                        % Regret request
                        app.ConfirmMessage.Text = "对方提出了悔棋";
                        app.ConfirmConfirm.Text = "同意";
                        app.ConfirmCancel.Text = "拒绝";
                        app.ConfirmWindow.Visible = "on";
                        app.ConfirmMessage.Visible = "on";
                        app.ConfirmConfirm.Visible = "on";
                        app.ConfirmCancel.Visible = "on";
                        results = 0;
                        return;
                    case 300
                        % Judged victory
                        win(app, 2);
                        results = 1;
                        return;
                    case 301
                        % Judged defeat
                        return;
                    case 302
                        % Judged draw
                        return;
                end
            end
        end

        function feedback(app, agree)
            if app.status == 100
                if agree
                    app.draw;
                else
                    app.ConfirmMessage.Text = "对方拒绝了和棋";
                    app.ConfirmWindow.Visible = "on";
                    app.ConfirmMessage.Visible = "on";
                    app.ConfirmConfirm.Text = "确定";
                    app.ConfirmWindow.Visible = "on";
                    app.ConfirmConfirm.Visible = "on";
                    app.ConfirmCancel.Visible = "off";
                    app.status = 0;
                end
            elseif app.status == 102
                if agree
                    app.regret = false;
                    app.ConfirmMessage.Text = "对方同意了悔棋";
                    app.ConfirmWindow.Visible = "on";
                    app.ConfirmMessage.Visible = "on";
                    app.ConfirmConfirm.Text = "确定";
                    app.ConfirmWindow.Visible = "on";
                    app.ConfirmConfirm.Visible = "on";
                    app.ConfirmCancel.Visible = "off";
                    app.status = 0;
                else
                    app.regret = false;
                    app.ConfirmMessage.Text = "对方拒绝了悔棋";
                    app.ConfirmWindow.Visible = "on";
                    app.ConfirmMessage.Visible = "on";
                    app.ConfirmConfirm.Text = "确定";
                    app.ConfirmWindow.Visible = "on";
                    app.ConfirmConfirm.Visible = "on";
                    app.ConfirmCancel.Visible = "off";
                    app.status = 0;
                end
            end
        end

        function win(app, type)
            % If won
            if (app.finished)
                return;
            end
            app.status = 300;
            app.deadline = 10000000;
            if type == 1
                % admit defeat
                app.ConfirmMessage.Text = "对方已认输";
                app.ConfirmWindow.Visible = "on";
                app.ConfirmMessage.Visible = "on";
                app.ConfirmConfirm.Text = "返回";
                app.ConfirmWindow.Visible = "on";
                app.ConfirmConfirm.Visible = "on";
                app.ConfirmCancel.Visible = "off";
            elseif type == 2
                % Judged victory
                app.ConfirmMessage.Text = "胜利";
                app.ConfirmWindow.Visible = "on";
                app.ConfirmMessage.Visible = "on";
                app.ConfirmConfirm.Text = "返回";
                app.ConfirmWindow.Visible = "on";
                app.ConfirmConfirm.Visible = "on";
                app.ConfirmCancel.Visible = "off";
            end
            app.finished = true;
        end
        
        function lose(app, type)
            % If lost
            if (app.finished)
                return;
            end
            app.status = 301;
            app.deadline = 10000000;
            if type == 1
                % admit defeat
                app.ConfirmMessage.Text = "您已认输";
                app.ConfirmWindow.Visible = "on";
                app.ConfirmMessage.Visible = "on";
                app.ConfirmConfirm.Text = "返回";
                app.ConfirmWindow.Visible = "on";
                app.ConfirmConfirm.Visible = "on";
                app.ConfirmCancel.Visible = "off";
            elseif type == 2
                % Judged defeat
                app.ConfirmMessage.Text = "失败";
                app.ConfirmWindow.Visible = "on";
                app.ConfirmMessage.Visible = "on";
                app.ConfirmConfirm.Text = "返回";
                app.ConfirmWindow.Visible = "on";
                app.ConfirmConfirm.Visible = "on";
                app.ConfirmCancel.Visible = "off";
            end
            app.finished = true;
        end
        
        function draw(app)
            % If draw
            if (app.finished)
                return;
            end
            app.status = 302;
            app.deadline = 10000000;
            app.ConfirmMessage.Text = "平局";
            app.ConfirmWindow.Visible = "on";
            app.ConfirmMessage.Visible = "on";
            app.ConfirmConfirm.Text = "返回";
            app.ConfirmWindow.Visible = "on";
            app.ConfirmConfirm.Visible = "on";
            app.ConfirmCancel.Visible = "off";
            app.finished = true;
        end

    end
    
    methods (Access = private)

        function Click(app, x, y) % Before calling this function, you should ensure that there is something (NOT "piece_0.png")
            if app.currentChoice.x == 0 && app.currentChoice.y == 0
                app.currentChoice.x = x;
                app.currentChoice.y = y;
                switch app.Direction
                    case "Red"
                        app.choiceBox.Position = [app.idx1(x) app.idx2(y) 100 100];
                    case "Black"
                        app.choiceBox.Position = [app.idx1(10 - x) app.idx2(11 - y) 100 100];
                end
                app.choiceBox.Visible = "on";
                app.AllowClickEmpty = true;
            elseif app.currentChoice.x == x && app.currentChoice.y == y
                app.currentChoice.x = 0;
                app.currentChoice.y = 0;
                app.choiceBox.Visible = "off";
                app.AllowClickEmpty = false;
            else
                app.ViewModel.move(app.currentChoice.x, app.currentChoice.y, x, y, app.Direction);
                app.currentChoice.x = 0;
                app.currentChoice.y = 0;
                app.choiceBox.Visible = "off";
                app.AllowClickEmpty = false;
            end
        end
        
        function refreshTime(app)
            if (app.deadline > 100000 || app.deadline < app.ViewModel.getTime || app.finished)
                app.DisplayTime.Text = "";
            else
                app.DisplayTime.Text = num2str(app.deadline - app.ViewModel.getTime);
            end
            if (app.deadline > 100000 & app.regret & app.ViewModel.canReg)          % Not my turn
                app.RegretRequest.Enable = "on";
                app.RegretRequest.BackgroundColor = [1.0 0.8 0.4];
            else
                app.RegretRequest.Enable = "off";
                app.RegretRequest.BackgroundColor = [0.5 0.5 0.5];
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, direction, VM)
            app.deadline = 100000000;               % Some random number large enough
            app.status = 0;
            app.AllowClickEmpty = false;
            app.idx1 = [61 171 285 396 504 615 724 835 948];
            app.idx2 = [999 896 786 676 565 448 335 223 113 2];
            app.currentChoice(1).x = 0;
            app.currentChoice(1).y = 0;
            app.ViewModel = VM;
            app.Direction = direction;
            app.localUpdateTiming = timer('Period', 0.5, "TimerFcn", @(~,~) app.refreshTime, "ExecutionMode", "fixedRate");
            app.localUpdateTiming.start;
            app.finished = false;
            app.regret = false;
        end

        % Button pushed function: DrawRequest
        function DrawRequestButtonPushed(app, event)
            app.ConfirmMessage.Text = "您确定要求和吗";
            app.ConfirmConfirm.Text = "确定";
            app.ConfirmCancel.Text = "取消";
            app.ConfirmWindow.Visible = "on";
            app.ConfirmMessage.Visible = "on";
            app.ConfirmConfirm.Visible = "on";
            app.ConfirmCancel.Visible = "on";
            app.status = 100;
        end

        % Button pushed function: DefeatRequest
        function DefeatRequestButtonPushed(app, event)
            app.ConfirmMessage.Text = "您确定要认输吗";
            app.ConfirmConfirm.Text = "确定";
            app.ConfirmCancel.Text = "取消";
            app.ConfirmWindow.Visible = "on";
            app.ConfirmMessage.Visible = "on";
            app.ConfirmConfirm.Visible = "on";
            app.ConfirmCancel.Visible = "on";
            app.status = 101;
        end

        % Button pushed function: RegretRequest
        function RegretRequestButtonPushed(app, event)
            app.ConfirmMessage.Text = "您确定要悔棋吗";
            app.ConfirmConfirm.Text = "确定";
            app.ConfirmCancel.Text = "取消";
            app.ConfirmWindow.Visible = "on";
            app.ConfirmMessage.Visible = "on";
            app.ConfirmConfirm.Visible = "on";
            app.ConfirmCancel.Visible = "on";
            app.status = 102;
        end

        % Button pushed function: ConfirmConfirm
        function ConfirmConfirmButtonPushed(app, event)
            app.ConfirmWindow.Visible = "off";
            app.ConfirmMessage.Visible = "off";
            app.ConfirmConfirm.Visible = "off";
            app.ConfirmCancel.Visible = "off";
            switch app.status
                case 100
                    app.ViewModel.RequestDraw();
                case 101
                    app.ViewModel.RequestDefeat();
                case 102
                    app.ViewModel.RequestUndo();
                case 200
                    app.status = 0;
                    app.ViewModel.reply(true, "draw");
                    app.draw;
                case 202
                    app.status = 0;
                    app.ViewModel.reply(true, "regret");
                case 300
                    app.ViewModel.EndGame;
                    delete(app);
                case 301
                    app.ViewModel.EndGame;
                    delete(app);
                case 302
                    app.ViewModel.EndGame;
                    delete(app);
                case 400
                    app.ViewModel.EndGame;
                    delete(app);
            end

        end

        % Button pushed function: ConfirmCancel
        function ConfirmCancelButtonPushed(app, event)
            app.ConfirmWindow.Visible = "off";
            app.ConfirmMessage.Visible = "off";
            app.ConfirmMessage.Text = "通知";
            app.ConfirmConfirm.Visible = "off";
            app.ConfirmCancel.Visible = "off";
            switch app.status
                case 200
                    app.ViewModel.reply(false, "draw");
                case 202
                    app.ViewModel.reply(false, "regret");
            end
            app.status = 0;
        end

        % Image clicked function: pos_1_1, pos_1_10, pos_1_2, pos_1_3, 
        % ...and 86 other components
        function ChessPieceImageClicked(app, event)
            switch app.Direction
                case "Red"
                    if event.Source.ImageSource ~= "imgs\piece_0.png" || app.AllowClickEmpty
                        app.Click(find(app.idx1 == event.Source.Position(1)), find(app.idx2 == event.Source.Position(2)));
                    end
                case "Black"
                    if event.Source.ImageSource ~= "imgs\piece_0.png" || app.AllowClickEmpty
                        app.Click(10 - find(app.idx1 == event.Source.Position(1)), 11 - find(app.idx2 == event.Source.Position(2)));
                    end
            end
        end

        % Image clicked function: choiceBox
        function choiceBoxImageClicked(app, event)
            switch app.Direction
                case "Red"
                    app.Click(find(app.idx1 == event.Source.Position(1)), find(app.idx2 == event.Source.Position(2)));
                case "Black"
                    app.Click(10 - find(app.idx1 == event.Source.Position(1)), 11 - find(app.idx2 == event.Source.Position(2)));
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.ViewModel.EndGame;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Background = uiimage(app.UIFigure);
            app.Background.ImageSource = 'Board.png';
            app.RegretRequest = uibutton(app.UIFigure, 'push');
            app.RegretRequest.ButtonPushedFcn = createCallbackFcn(app, @RegretRequestButtonPushed, true);
            app.RegretRequest.Enable = 'off';
            app.RegretRequest.Text = '悔棋';
            app.DefeatRequest = uibutton(app.UIFigure, 'push');
            app.DefeatRequest.ButtonPushedFcn = createCallbackFcn(app, @DefeatRequestButtonPushed, true);
            app.DefeatRequest.Text = '认输';
            app.DrawRequest = uibutton(app.UIFigure, 'push');
            app.DrawRequest.ButtonPushedFcn = createCallbackFcn(app, @DrawRequestButtonPushed, true);
            app.DrawRequest.Text = '求和';
            app.pos_1_1 = uiimage(app.UIFigure);
            app.pos_1_1.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_1_1.ImageSource = 'piece_0.png';
            app.pos_1_2 = uiimage(app.UIFigure);
            app.pos_1_2.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_1_2.ImageSource = 'piece_0.png';
            app.pos_1_3 = uiimage(app.UIFigure);
            app.pos_1_3.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_1_3.ImageSource = 'piece_0.png';
            app.pos_1_4 = uiimage(app.UIFigure);
            app.pos_1_4.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_1_4.ImageSource = 'piece_0.png';
            app.pos_1_5 = uiimage(app.UIFigure);
            app.pos_1_5.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_1_5.ImageSource = 'piece_0.png';
            app.pos_1_6 = uiimage(app.UIFigure);
            app.pos_1_6.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_1_6.ImageSource = 'piece_0.png';
            app.pos_1_7 = uiimage(app.UIFigure);
            app.pos_1_7.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_1_7.ImageSource = 'piece_0.png';
            app.pos_1_8 = uiimage(app.UIFigure);
            app.pos_1_8.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_1_8.ImageSource = 'piece_0.png';
            app.pos_1_9 = uiimage(app.UIFigure);
            app.pos_1_9.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_1_9.ImageSource = 'piece_0.png';
            app.pos_1_10 = uiimage(app.UIFigure);
            app.pos_1_10.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_1_10.ImageSource = 'piece_0.png';
            app.pos_2_1 = uiimage(app.UIFigure);
            app.pos_2_1.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_2_1.ImageSource = 'piece_0.png';
            app.pos_2_2 = uiimage(app.UIFigure);
            app.pos_2_2.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_2_2.ImageSource = 'piece_0.png';
            app.pos_2_3 = uiimage(app.UIFigure);
            app.pos_2_3.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_2_3.ImageSource = 'piece_0.png';
            app.pos_2_4 = uiimage(app.UIFigure);
            app.pos_2_4.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_2_4.ImageSource = 'piece_0.png';
            app.pos_2_5 = uiimage(app.UIFigure);
            app.pos_2_5.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_2_5.ImageSource = 'piece_0.png';
            app.pos_2_6 = uiimage(app.UIFigure);
            app.pos_2_6.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_2_6.ImageSource = 'piece_0.png';
            app.pos_2_7 = uiimage(app.UIFigure);
            app.pos_2_7.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_2_7.ImageSource = 'piece_0.png';
            app.pos_2_8 = uiimage(app.UIFigure);
            app.pos_2_8.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_2_8.ImageSource = 'piece_0.png';
            app.pos_2_9 = uiimage(app.UIFigure);
            app.pos_2_9.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_2_9.ImageSource = 'piece_0.png';
            app.pos_2_10 = uiimage(app.UIFigure);
            app.pos_2_10.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_2_10.ImageSource = 'piece_0.png';
            app.pos_3_1 = uiimage(app.UIFigure);
            app.pos_3_1.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_3_1.ImageSource = 'piece_0.png';
            app.pos_3_2 = uiimage(app.UIFigure);
            app.pos_3_2.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_3_2.ImageSource = 'piece_0.png';
            app.pos_3_3 = uiimage(app.UIFigure);
            app.pos_3_3.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_3_3.ImageSource = 'piece_0.png';
            app.pos_3_4 = uiimage(app.UIFigure);
            app.pos_3_4.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_3_4.ImageSource = 'piece_0.png';
            app.pos_3_5 = uiimage(app.UIFigure);
            app.pos_3_5.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_3_5.ImageSource = 'piece_0.png';
            app.pos_3_6 = uiimage(app.UIFigure);
            app.pos_3_6.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_3_6.ImageSource = 'piece_0.png';
            app.pos_3_7 = uiimage(app.UIFigure);
            app.pos_3_7.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_3_7.ImageSource = 'piece_0.png';
            app.pos_3_8 = uiimage(app.UIFigure);
            app.pos_3_8.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_3_8.ImageSource = 'piece_0.png';
            app.pos_3_9 = uiimage(app.UIFigure);
            app.pos_3_9.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_3_9.ImageSource = 'piece_0.png';
            app.pos_3_10 = uiimage(app.UIFigure);
            app.pos_3_10.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_3_10.ImageSource = 'piece_0.png';
            app.pos_4_1 = uiimage(app.UIFigure);
            app.pos_4_1.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_4_1.ImageSource = 'piece_0.png';
            app.pos_4_2 = uiimage(app.UIFigure);
            app.pos_4_2.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_4_2.ImageSource = 'piece_0.png';
            app.pos_4_3 = uiimage(app.UIFigure);
            app.pos_4_3.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_4_3.ImageSource = 'piece_0.png';
            app.pos_4_4 = uiimage(app.UIFigure);
            app.pos_4_4.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_4_4.ImageSource = 'piece_0.png';
            app.pos_4_5 = uiimage(app.UIFigure);
            app.pos_4_5.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_4_5.ImageSource = 'piece_0.png';
            app.pos_4_6 = uiimage(app.UIFigure);
            app.pos_4_6.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_4_6.ImageSource = 'piece_0.png';
            app.pos_4_7 = uiimage(app.UIFigure);
            app.pos_4_7.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_4_7.ImageSource = 'piece_0.png';
            app.pos_4_8 = uiimage(app.UIFigure);
            app.pos_4_8.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_4_8.ImageSource = 'piece_0.png';
            app.pos_4_9 = uiimage(app.UIFigure);
            app.pos_4_9.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_4_9.ImageSource = 'piece_0.png';
            app.pos_4_10 = uiimage(app.UIFigure);
            app.pos_4_10.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_4_10.ImageSource = 'piece_0.png';
            app.pos_5_1 = uiimage(app.UIFigure);
            app.pos_5_1.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_5_1.ImageSource = 'piece_0.png';
            app.pos_5_2 = uiimage(app.UIFigure);
            app.pos_5_2.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_5_2.ImageSource = 'piece_0.png';
            app.pos_5_3 = uiimage(app.UIFigure);
            app.pos_5_3.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_5_3.ImageSource = 'piece_0.png';
            app.pos_5_4 = uiimage(app.UIFigure);
            app.pos_5_4.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_5_4.ImageSource = 'piece_0.png';
            app.pos_5_5 = uiimage(app.UIFigure);
            app.pos_5_5.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_5_5.ImageSource = 'piece_0.png';
            app.pos_5_6 = uiimage(app.UIFigure);
            app.pos_5_6.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_5_6.ImageSource = 'piece_0.png';
            app.pos_5_7 = uiimage(app.UIFigure);
            app.pos_5_7.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_5_7.ImageSource = 'piece_0.png';
            app.pos_5_8 = uiimage(app.UIFigure);
            app.pos_5_8.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_5_8.ImageSource = 'piece_0.png';
            app.pos_5_9 = uiimage(app.UIFigure);
            app.pos_5_9.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_5_9.ImageSource = 'piece_0.png';
            app.pos_5_10 = uiimage(app.UIFigure);
            app.pos_5_10.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_5_10.ImageSource = 'piece_0.png';
            app.pos_6_1 = uiimage(app.UIFigure);
            app.pos_6_1.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_6_1.ImageSource = 'piece_0.png';
            app.pos_6_2 = uiimage(app.UIFigure);
            app.pos_6_2.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_6_2.ImageSource = 'piece_0.png';
            app.pos_6_3 = uiimage(app.UIFigure);
            app.pos_6_3.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_6_3.ImageSource = 'piece_0.png';
            app.pos_6_4 = uiimage(app.UIFigure);
            app.pos_6_4.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_6_4.ImageSource = 'piece_0.png';
            app.pos_6_5 = uiimage(app.UIFigure);
            app.pos_6_5.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_6_5.ImageSource = 'piece_0.png';
            app.pos_6_6 = uiimage(app.UIFigure);
            app.pos_6_6.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_6_6.ImageSource = 'piece_0.png';
            app.pos_6_7 = uiimage(app.UIFigure);
            app.pos_6_7.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_6_7.ImageSource = 'piece_0.png';
            app.pos_6_8 = uiimage(app.UIFigure);
            app.pos_6_8.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_6_8.ImageSource = 'piece_0.png';
            app.pos_6_9 = uiimage(app.UIFigure);
            app.pos_6_9.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_6_9.ImageSource = 'piece_0.png';
            app.pos_6_10 = uiimage(app.UIFigure);
            app.pos_6_10.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_6_10.ImageSource = 'piece_0.png';
            app.pos_7_1 = uiimage(app.UIFigure);
            app.pos_7_1.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_7_1.ImageSource = 'piece_0.png';
            app.pos_7_2 = uiimage(app.UIFigure);
            app.pos_7_2.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_7_2.ImageSource = 'piece_0.png';
            app.pos_7_3 = uiimage(app.UIFigure);
            app.pos_7_3.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_7_3.ImageSource = 'piece_0.png';
            app.pos_7_4 = uiimage(app.UIFigure);
            app.pos_7_4.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_7_4.ImageSource = 'piece_0.png';
            app.pos_7_5 = uiimage(app.UIFigure);
            app.pos_7_5.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_7_5.ImageSource = 'piece_0.png';
            app.pos_7_6 = uiimage(app.UIFigure);
            app.pos_7_6.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_7_6.ImageSource = 'piece_0.png';
            app.pos_7_7 = uiimage(app.UIFigure);
            app.pos_7_7.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_7_7.ImageSource = 'piece_0.png';
            app.pos_7_8 = uiimage(app.UIFigure);
            app.pos_7_8.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_7_8.ImageSource = 'piece_0.png';
            app.pos_7_9 = uiimage(app.UIFigure);
            app.pos_7_9.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_7_9.ImageSource = 'piece_0.png';
            app.pos_7_10 = uiimage(app.UIFigure);
            app.pos_7_10.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_7_10.ImageSource = 'piece_0.png';
            app.pos_8_1 = uiimage(app.UIFigure);
            app.pos_8_1.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_8_1.ImageSource = 'piece_0.png';
            app.pos_8_2 = uiimage(app.UIFigure);
            app.pos_8_2.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_8_2.ImageSource = 'piece_0.png';
            app.pos_8_3 = uiimage(app.UIFigure);
            app.pos_8_3.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_8_3.ImageSource = 'piece_0.png';
            app.pos_8_4 = uiimage(app.UIFigure);
            app.pos_8_4.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_8_4.ImageSource = 'piece_0.png';
            app.pos_8_5 = uiimage(app.UIFigure);
            app.pos_8_5.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_8_5.ImageSource = 'piece_0.png';
            app.pos_8_6 = uiimage(app.UIFigure);
            app.pos_8_6.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_8_6.ImageSource = 'piece_0.png';
            app.pos_8_7 = uiimage(app.UIFigure);
            app.pos_8_7.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_8_7.ImageSource = 'piece_0.png';
            app.pos_8_8 = uiimage(app.UIFigure);
            app.pos_8_8.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_8_8.ImageSource = 'piece_0.png';
            app.pos_8_9 = uiimage(app.UIFigure);
            app.pos_8_9.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_8_9.ImageSource = 'piece_0.png';
            app.pos_8_10 = uiimage(app.UIFigure);
            app.pos_8_10.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_8_10.ImageSource = 'piece_0.png';
            app.pos_9_1 = uiimage(app.UIFigure);
            app.pos_9_1.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_9_1.ImageSource = 'piece_0.png';
            app.pos_9_2 = uiimage(app.UIFigure);
            app.pos_9_2.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_9_2.ImageSource = 'piece_0.png';
            app.pos_9_3 = uiimage(app.UIFigure);
            app.pos_9_3.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_9_3.ImageSource = 'piece_0.png';
            app.pos_9_4 = uiimage(app.UIFigure);
            app.pos_9_4.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_9_4.ImageSource = 'piece_0.png';
            app.pos_9_5 = uiimage(app.UIFigure);
            app.pos_9_5.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_9_5.ImageSource = 'piece_0.png';
            app.pos_9_6 = uiimage(app.UIFigure);
            app.pos_9_6.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_9_6.ImageSource = 'piece_0.png';
            app.pos_9_7 = uiimage(app.UIFigure);
            app.pos_9_7.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_9_7.ImageSource = 'piece_0.png';
            app.pos_9_8 = uiimage(app.UIFigure);
            app.pos_9_8.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_9_8.ImageSource = 'piece_0.png';
            app.pos_9_9 = uiimage(app.UIFigure);
            app.pos_9_9.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_9_9.ImageSource = 'piece_0.png';
            app.pos_9_10 = uiimage(app.UIFigure);
            app.pos_9_10.ImageClickedFcn = createCallbackFcn(app, @ChessPieceImageClicked, true);
            app.pos_9_10.ImageSource = 'piece_0.png';
            app.DisplayTime = uilabel(app.UIFigure);
            app.DisplayTime.Text = '';
            app.choiceBox = uiimage(app.UIFigure);
            app.choiceBox.ImageClickedFcn = createCallbackFcn(app, @choiceBoxImageClicked, true);
            app.choiceBox.Visible = 'off';
            app.choiceBox.ImageSource = 'choiceBox.png';
            app.ConfirmWindow = uiimage(app.UIFigure);
            app.ConfirmWindow.Tag = 'confirm';
            app.ConfirmWindow.Visible = 'off';
            app.ConfirmWindow.ImageSource = 'Confirm.png';
            app.ConfirmMessage = uilabel(app.UIFigure);
            app.ConfirmMessage.Tag = 'confirm';
            app.ConfirmMessage.Visible = 'off';
            app.ConfirmMessage.Text = '通知';
            app.ConfirmConfirm = uibutton(app.UIFigure, 'push');
            app.ConfirmConfirm.ButtonPushedFcn = createCallbackFcn(app, @ConfirmConfirmButtonPushed, true);
            app.ConfirmConfirm.Tag = 'confirm';
            app.ConfirmConfirm.Visible = 'off';
            app.ConfirmConfirm.Text = '确定';
            app.ConfirmCancel = uibutton(app.UIFigure, 'push');
            app.ConfirmCancel.ButtonPushedFcn = createCallbackFcn(app, @ConfirmCancelButtonPushed, true);
            app.ConfirmCancel.Tag = 'confirm';
            app.ConfirmCancel.Visible = 'off';
            app.ConfirmCancel.Text = '取消';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GameBoard(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end