classdef LogPlayer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        exit         matlab.ui.control.Button
        TextArea     matlab.ui.control.TextArea
        fastforward  matlab.ui.control.Image
        choiceBox    matlab.ui.control.Image
        DisplayTime  matlab.ui.control.Label
        pos_9_10     matlab.ui.control.Image
        pos_9_9      matlab.ui.control.Image
        pos_9_8      matlab.ui.control.Image
        pos_9_7      matlab.ui.control.Image
        pos_9_6      matlab.ui.control.Image
        pos_9_5      matlab.ui.control.Image
        pos_9_4      matlab.ui.control.Image
        pos_9_3      matlab.ui.control.Image
        pos_9_2      matlab.ui.control.Image
        pos_9_1      matlab.ui.control.Image
        pos_8_10     matlab.ui.control.Image
        pos_8_9      matlab.ui.control.Image
        pos_8_8      matlab.ui.control.Image
        pos_8_7      matlab.ui.control.Image
        pos_8_6      matlab.ui.control.Image
        pos_8_5      matlab.ui.control.Image
        pos_8_4      matlab.ui.control.Image
        pos_8_3      matlab.ui.control.Image
        pos_8_2      matlab.ui.control.Image
        pos_8_1      matlab.ui.control.Image
        pos_7_10     matlab.ui.control.Image
        pos_7_9      matlab.ui.control.Image
        pos_7_8      matlab.ui.control.Image
        pos_7_7      matlab.ui.control.Image
        pos_7_6      matlab.ui.control.Image
        pos_7_5      matlab.ui.control.Image
        pos_7_4      matlab.ui.control.Image
        pos_7_3      matlab.ui.control.Image
        pos_7_2      matlab.ui.control.Image
        pos_7_1      matlab.ui.control.Image
        pos_6_10     matlab.ui.control.Image
        pos_6_9      matlab.ui.control.Image
        pos_6_8      matlab.ui.control.Image
        pos_6_7      matlab.ui.control.Image
        pos_6_6      matlab.ui.control.Image
        pos_6_5      matlab.ui.control.Image
        pos_6_4      matlab.ui.control.Image
        pos_6_3      matlab.ui.control.Image
        pos_6_2      matlab.ui.control.Image
        pos_6_1      matlab.ui.control.Image
        pos_5_10     matlab.ui.control.Image
        pos_5_9      matlab.ui.control.Image
        pos_5_8      matlab.ui.control.Image
        pos_5_7      matlab.ui.control.Image
        pos_5_6      matlab.ui.control.Image
        pos_5_5      matlab.ui.control.Image
        pos_5_4      matlab.ui.control.Image
        pos_5_3      matlab.ui.control.Image
        pos_5_2      matlab.ui.control.Image
        pos_5_1      matlab.ui.control.Image
        pos_4_10     matlab.ui.control.Image
        pos_4_9      matlab.ui.control.Image
        pos_4_8      matlab.ui.control.Image
        pos_4_7      matlab.ui.control.Image
        pos_4_6      matlab.ui.control.Image
        pos_4_5      matlab.ui.control.Image
        pos_4_4      matlab.ui.control.Image
        pos_4_3      matlab.ui.control.Image
        pos_4_2      matlab.ui.control.Image
        pos_4_1      matlab.ui.control.Image
        pos_3_10     matlab.ui.control.Image
        pos_3_9      matlab.ui.control.Image
        pos_3_8      matlab.ui.control.Image
        pos_3_7      matlab.ui.control.Image
        pos_3_6      matlab.ui.control.Image
        pos_3_5      matlab.ui.control.Image
        pos_3_4      matlab.ui.control.Image
        pos_3_3      matlab.ui.control.Image
        pos_3_2      matlab.ui.control.Image
        pos_3_1      matlab.ui.control.Image
        pos_2_10     matlab.ui.control.Image
        pos_2_9      matlab.ui.control.Image
        pos_2_8      matlab.ui.control.Image
        pos_2_7      matlab.ui.control.Image
        pos_2_6      matlab.ui.control.Image
        pos_2_5      matlab.ui.control.Image
        pos_2_4      matlab.ui.control.Image
        pos_2_3      matlab.ui.control.Image
        pos_2_2      matlab.ui.control.Image
        pos_2_1      matlab.ui.control.Image
        pos_1_10     matlab.ui.control.Image
        pos_1_9      matlab.ui.control.Image
        pos_1_8      matlab.ui.control.Image
        pos_1_7      matlab.ui.control.Image
        pos_1_6      matlab.ui.control.Image
        pos_1_5      matlab.ui.control.Image
        pos_1_4      matlab.ui.control.Image
        pos_1_3      matlab.ui.control.Image
        pos_1_2      matlab.ui.control.Image
        pos_1_1      matlab.ui.control.Image
        Background   matlab.ui.control.Image
    end

    
    properties (Access = private)
        log                 LogKeeper
        localUpdateTiming   timer
        time                double
        finished            logical
        nowait              logical
        board               int16
        prev                int16
        idx1                int16
        idx2                int16
    end

    properties (Access = public)
        deadline            int64           % The time of move
    end
    
    methods (Access = public)

        function result = parseNewLine(app)
            newline = app.log.nextLine;
            result = false;
            switch (newline(1))
                case "0"
                    app.finish;
                case "regret"
                    app.recSpecialEvent(newline);
                    app.board = app.prev;
                    app.LoadBoardFromApp;
                    app.deadline = app.time + 1;
                case "draw"
                    app.recSpecialEvent(newline);
                    app.deadline = app.time + 1;
                case "surrender"
                    app.recSpecialEvent(newline);
                    app.deadline = app.time + 1;
                case "result"
                    app.recSpecialEvent(newline);
                    app.deadline = app.time + 1;
                otherwise
                    app.UpdateBoard(newline(1), newline(2), newline(3), newline(4));
                    if app.nowait
                        app.deadline = app.time + 1;
                    else
                        app.deadline = app.time + str2double(newline(5));
                    end
                    result = true;
            end
        end

        function start(app)
            app.deadline = 0;
            app.refreshTime;
        end

        function finish(app)
            app.exit.Visible = "on";
        end
    end
    
    methods (Access = private)

        function LoadBoardFromApp(app)
            for i = 1:9
                for j = 1:10
                    name = "pos_" + int2str(i) + "_" + int2str(j);
                    app.(matlab.lang.makeValidName(name)).ImageSource = "imgs/piece_" + int2str(app.board(i, j)) + ".png";
                end
            end
        end

        function UpdateBoard(app, x, y, newX, newY)
            app.prev = app.board;
            x = str2double(x);
            y = str2double(y);
            newX = str2double(newX);
            newY = str2double(newY);
            app.writeText(x, y, newX, newY);
            app.board(newX, newY) = app.board(x, y);
            app.board(x, y) = 0;
            app.LoadBoardFromApp;
            app.choiceBox.Position = [app.idx1(x) app.idx2(y) 100 100];
            app.choiceBox.Enable;
        end

        function recSpecialEvent(app, line)
            switch line(1)
                case "regret"
                    if line(2) == "Red"
                        app.TextArea.Value(end) = cellstr(string(app.TextArea.Value(end)) + "红方悔棋");
                        app.TextArea.Value(end) = cellstr(string(app.TextArea.Value(end)) + newline + " ");
                    else
                        app.TextArea.Value(end) = cellstr(string(app.TextArea.Value(end)) + "黑方悔棋");
                        app.TextArea.Value(end) = cellstr(string(app.TextArea.Value(end)) + newline + " ");
                    end
                case "draw"
                    if line(2) == "Red"
                        app.TextArea.Value(end) = cellstr(string(app.TextArea.Value(end)) + newline + " 红方求和");
                    else
                        app.TextArea.Value(end) = cellstr(string(app.TextArea.Value(end)) + newline + " 黑方求和");
                    end
                case "surrender"
                    if line(2) == "Red"
                        app.TextArea.Value(end) = cellstr(string(app.TextArea.Value(end)) + newline + " 红方认输");
                    else
                        app.TextArea.Value(end) = cellstr(string(app.TextArea.Value(end)) + newline + " 黑方认输");
                    end
                case "result"
                    if line(2) == "Red"
                        app.TextArea.Value(end) = cellstr(string(app.TextArea.Value(end)) + newline + " 红方获胜");
                    else
                        app.TextArea.Value(end) = cellstr(string(app.TextArea.Value(end)) + newline + " 黑方获胜");
                    end
            end
        end

        function writeText(app, x, y, newX, newY)
            name = ["将", "士", "象", "马", "车", "炮", "卒", "帅", "仕", "相", "㐷", "伡", "砲", "兵"];
            num = ["一", "二", "三", "四", "五", "六", "七", "八", "九", "十"];
            if app.board(x, y) >= 8
                if y == newY
                    text = name(app.board(x, y)) + num(x) + "平" + num(newX);
                elseif (y < newY & app.board(x, y) <= 7) | (y > newY & app.board(x, y) >= 8)
                    text = name(app.board(x, y)) + num(y) + "进" + num(newY);
                else
                    text = name(app.board(x, y)) + num(y) + "退" + num(newY);
                end
                app.TextArea.Value(end) = cellstr(string(app.TextArea.Value(end)) + text + " ");
            else
                if y == newY
                    text = name(app.board(x, y)) + num2str(x) + "平" + num2str(newX);
                elseif (y < newY & app.board(x, y) <= 7) | (y > newY & app.board(x, y) >= 8)
                    text = name(app.board(x, y)) + num2str(y) + "进" + num2str(newY);
                else
                    text = name(app.board(x, y)) + num2str(y) + "退" + num2str(newY);
                end
                app.TextArea.Value(end) = cellstr(string(app.TextArea.Value(end)) + text + newline);
                app.TextArea.Value(end) = cellstr(string(app.TextArea.Value(end)) + newline + " ");
            end
        end
        
        function refreshTime(app)
            if (app.deadline > 100000 | app.deadline < app.time | app.finished | app.nowait)
                app.DisplayTime.Text = "";
            else
                app.DisplayTime.Text = num2str(app.deadline - app.time);
            end
            app.time = app.time + 0.5;
            if (app.time >= app.deadline)
                app.parseNewLine;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, log)
            app.log = log;
            app.deadline = 1;
            app.time = 0;
            app.localUpdateTiming = timer('Period', 0.5, "TimerFcn", @(~,~) app.refreshTime, "ExecutionMode", "fixedRate");
            app.localUpdateTiming.start;
            app.finished = false;
            app.idx1 = [61 171 285 396 504 615 724 835 948];
            app.idx2 = [999 896 786 676 565 448 335 223 113 2];
            app.nowait = false;
            app.board = [[5 4 3 2 1 2 3 4 5];
                        [0 0 0 0 0 0 0 0 0];
                        [0 6 0 0 0 0 0 6 0];
                        [7 0 7 0 7 0 7 0 7];
                        [0 0 0 0 0 0 0 0 0];
                        [0 0 0 0 0 0 0 0 0];
                        [14 0 14 0 14 0 14 0 14];
                        [0 13 0 0 0 0 0 13 0];
                        [0 0 0 0 0 0 0 0 0];
                        [12 11 10 9 8 9 10 11 12];]';
            app.prev = app.board;
        end

        % Image clicked function: fastforward
        function fastforwardImageClicked(app, event)
            app.nowait = true;
            app.deadline = app.time + 0.5;
            app.fastforward.Visible = "off";
        end

        % Button pushed function: exit
        function exitButtonPushed(app, event)
            app.log.Finish;
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.log.Finish;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Background = uiimage(app.UIFigure);
            app.Background.ImageSource = 'Board.png';
            app.pos_1_1 = uiimage(app.UIFigure);
            app.pos_1_1.ImageSource = 'piece_0.png';
            app.pos_1_2 = uiimage(app.UIFigure);
            app.pos_1_2.ImageSource = 'piece_0.png';
            app.pos_1_3 = uiimage(app.UIFigure);
            app.pos_1_3.ImageSource = 'piece_0.png';
            app.pos_1_4 = uiimage(app.UIFigure);
            app.pos_1_4.ImageSource = 'piece_0.png';
            app.pos_1_5 = uiimage(app.UIFigure);
            app.pos_1_5.ImageSource = 'piece_0.png';
            app.pos_1_6 = uiimage(app.UIFigure);
            app.pos_1_6.ImageSource = 'piece_0.png';
            app.pos_1_7 = uiimage(app.UIFigure);
            app.pos_1_7.ImageSource = 'piece_0.png';
            app.pos_1_8 = uiimage(app.UIFigure);
            app.pos_1_8.ImageSource = 'piece_0.png';
            app.pos_1_9 = uiimage(app.UIFigure);
            app.pos_1_9.ImageSource = 'piece_0.png';
            app.pos_1_10 = uiimage(app.UIFigure);
            app.pos_1_10.ImageSource = 'piece_0.png';
            app.pos_2_1 = uiimage(app.UIFigure);
            app.pos_2_1.ImageSource = 'piece_0.png';
            app.pos_2_2 = uiimage(app.UIFigure);
            app.pos_2_2.ImageSource = 'piece_0.png';
            app.pos_2_3 = uiimage(app.UIFigure);
            app.pos_2_3.ImageSource = 'piece_0.png';
            app.pos_2_4 = uiimage(app.UIFigure);
            app.pos_2_4.ImageSource = 'piece_0.png';
            app.pos_2_5 = uiimage(app.UIFigure);
            app.pos_2_5.ImageSource = 'piece_0.png';
            app.pos_2_6 = uiimage(app.UIFigure);
            app.pos_2_6.ImageSource = 'piece_0.png';
            app.pos_2_7 = uiimage(app.UIFigure);
            app.pos_2_7.ImageSource = 'piece_0.png';
            app.pos_2_8 = uiimage(app.UIFigure);
            app.pos_2_8.ImageSource = 'piece_0.png';
            app.pos_2_9 = uiimage(app.UIFigure);
            app.pos_2_9.ImageSource = 'piece_0.png';
            app.pos_2_10 = uiimage(app.UIFigure);
            app.pos_2_10.ImageSource = 'piece_0.png';
            app.pos_3_1 = uiimage(app.UIFigure);
            app.pos_3_1.ImageSource = 'piece_0.png';
            app.pos_3_2 = uiimage(app.UIFigure);
            app.pos_3_2.ImageSource = 'piece_0.png';
            app.pos_3_3 = uiimage(app.UIFigure);
            app.pos_3_3.ImageSource = 'piece_0.png';
            app.pos_3_4 = uiimage(app.UIFigure);
            app.pos_3_4.ImageSource = 'piece_0.png';
            app.pos_3_5 = uiimage(app.UIFigure);
            app.pos_3_5.ImageSource = 'piece_0.png';
            app.pos_3_6 = uiimage(app.UIFigure);
            app.pos_3_6.ImageSource = 'piece_0.png';
            app.pos_3_7 = uiimage(app.UIFigure);
            app.pos_3_7.ImageSource = 'piece_0.png';
            app.pos_3_8 = uiimage(app.UIFigure);
            app.pos_3_8.ImageSource = 'piece_0.png';
            app.pos_3_9 = uiimage(app.UIFigure);
            app.pos_3_9.ImageSource = 'piece_0.png';
            app.pos_3_10 = uiimage(app.UIFigure);
            app.pos_3_10.ImageSource = 'piece_0.png';
            app.pos_4_1 = uiimage(app.UIFigure);
            app.pos_4_1.ImageSource = 'piece_0.png';
            app.pos_4_2 = uiimage(app.UIFigure);
            app.pos_4_2.ImageSource = 'piece_0.png';
            app.pos_4_3 = uiimage(app.UIFigure);
            app.pos_4_3.ImageSource = 'piece_0.png';
            app.pos_4_4 = uiimage(app.UIFigure);
            app.pos_4_4.ImageSource = 'piece_0.png';
            app.pos_4_5 = uiimage(app.UIFigure);
            app.pos_4_5.ImageSource = 'piece_0.png';
            app.pos_4_6 = uiimage(app.UIFigure);
            app.pos_4_6.ImageSource = 'piece_0.png';
            app.pos_4_7 = uiimage(app.UIFigure);
            app.pos_4_7.ImageSource = 'piece_0.png';
            app.pos_4_8 = uiimage(app.UIFigure);
            app.pos_4_8.ImageSource = 'piece_0.png';
            app.pos_4_9 = uiimage(app.UIFigure);
            app.pos_4_9.ImageSource = 'piece_0.png';
            app.pos_4_10 = uiimage(app.UIFigure);
            app.pos_4_10.ImageSource = 'piece_0.png';
            app.pos_5_1 = uiimage(app.UIFigure);
            app.pos_5_1.ImageSource = 'piece_0.png';
            app.pos_5_2 = uiimage(app.UIFigure);
            app.pos_5_2.ImageSource = 'piece_0.png';
            app.pos_5_3 = uiimage(app.UIFigure);
            app.pos_5_3.ImageSource = 'piece_0.png';
            app.pos_5_4 = uiimage(app.UIFigure);
            app.pos_5_4.ImageSource = 'piece_0.png';
            app.pos_5_5 = uiimage(app.UIFigure);
            app.pos_5_5.ImageSource = 'piece_0.png';
            app.pos_5_6 = uiimage(app.UIFigure);
            app.pos_5_6.ImageSource = 'piece_0.png';
            app.pos_5_7 = uiimage(app.UIFigure);
            app.pos_5_7.ImageSource = 'piece_0.png';
            app.pos_5_8 = uiimage(app.UIFigure);
            app.pos_5_8.ImageSource = 'piece_0.png';
            app.pos_5_9 = uiimage(app.UIFigure);
            app.pos_5_9.ImageSource = 'piece_0.png';
            app.pos_5_10 = uiimage(app.UIFigure);
            app.pos_5_10.ImageSource = 'piece_0.png';
            app.pos_6_1 = uiimage(app.UIFigure);
            app.pos_6_1.ImageSource = 'piece_0.png';
            app.pos_6_2 = uiimage(app.UIFigure);
            app.pos_6_2.ImageSource = 'piece_0.png';
            app.pos_6_3 = uiimage(app.UIFigure);
            app.pos_6_3.ImageSource = 'piece_0.png';
            app.pos_6_4 = uiimage(app.UIFigure);
            app.pos_6_4.ImageSource = 'piece_0.png';
            app.pos_6_5 = uiimage(app.UIFigure);
            app.pos_6_5.ImageSource = 'piece_0.png';
            app.pos_6_6 = uiimage(app.UIFigure);
            app.pos_6_6.ImageSource = 'piece_0.png';
            app.pos_6_7 = uiimage(app.UIFigure);
            app.pos_6_7.ImageSource = 'piece_0.png';
            app.pos_6_8 = uiimage(app.UIFigure);
            app.pos_6_8.ImageSource = 'piece_0.png';
            app.pos_6_9 = uiimage(app.UIFigure);
            app.pos_6_9.ImageSource = 'piece_0.png';
            app.pos_6_10 = uiimage(app.UIFigure);
            app.pos_6_10.ImageSource = 'piece_0.png';
            app.pos_7_1 = uiimage(app.UIFigure);
            app.pos_7_1.ImageSource = 'piece_0.png';
            app.pos_7_2 = uiimage(app.UIFigure);
            app.pos_7_2.ImageSource = 'piece_0.png';
            app.pos_7_3 = uiimage(app.UIFigure);
            app.pos_7_3.ImageSource = 'piece_0.png';
            app.pos_7_4 = uiimage(app.UIFigure);
            app.pos_7_4.ImageSource = 'piece_0.png';
            app.pos_7_5 = uiimage(app.UIFigure);
            app.pos_7_5.ImageSource = 'piece_0.png';
            app.pos_7_6 = uiimage(app.UIFigure);
            app.pos_7_6.ImageSource = 'piece_0.png';
            app.pos_7_7 = uiimage(app.UIFigure);
            app.pos_7_7.ImageSource = 'piece_0.png';
            app.pos_7_8 = uiimage(app.UIFigure);
            app.pos_7_8.ImageSource = 'piece_0.png';
            app.pos_7_9 = uiimage(app.UIFigure);
            app.pos_7_9.ImageSource = 'piece_0.png';
            app.pos_7_10 = uiimage(app.UIFigure);
            app.pos_7_10.ImageSource = 'piece_0.png';
            app.pos_8_1 = uiimage(app.UIFigure);
            app.pos_8_1.ImageSource = 'piece_0.png';
            app.pos_8_2 = uiimage(app.UIFigure);
            app.pos_8_2.ImageSource = 'piece_0.png';
            app.pos_8_3 = uiimage(app.UIFigure);
            app.pos_8_3.ImageSource = 'piece_0.png';
            app.pos_8_4 = uiimage(app.UIFigure);
            app.pos_8_4.ImageSource = 'piece_0.png';
            app.pos_8_5 = uiimage(app.UIFigure);
            app.pos_8_5.ImageSource = 'piece_0.png';
            app.pos_8_6 = uiimage(app.UIFigure);
            app.pos_8_6.ImageSource = 'piece_0.png';
            app.pos_8_7 = uiimage(app.UIFigure);
            app.pos_8_7.ImageSource = 'piece_0.png';
            app.pos_8_8 = uiimage(app.UIFigure);
            app.pos_8_8.ImageSource = 'piece_0.png';
            app.pos_8_9 = uiimage(app.UIFigure);
            app.pos_8_9.ImageSource = 'piece_0.png';
            app.pos_8_10 = uiimage(app.UIFigure);
            app.pos_8_10.ImageSource = 'piece_0.png';
            app.pos_9_1 = uiimage(app.UIFigure);
            app.pos_9_1.ImageSource = 'piece_0.png';
            app.pos_9_2 = uiimage(app.UIFigure);
            app.pos_9_2.ImageSource = 'piece_0.png';
            app.pos_9_3 = uiimage(app.UIFigure);
            app.pos_9_3.ImageSource = 'piece_0.png';
            app.pos_9_4 = uiimage(app.UIFigure);
            app.pos_9_4.ImageSource = 'piece_0.png';
            app.pos_9_5 = uiimage(app.UIFigure);
            app.pos_9_5.ImageSource = 'piece_0.png';
            app.pos_9_6 = uiimage(app.UIFigure);
            app.pos_9_6.ImageSource = 'piece_0.png';
            app.pos_9_7 = uiimage(app.UIFigure);
            app.pos_9_7.ImageSource = 'piece_0.png';
            app.pos_9_8 = uiimage(app.UIFigure);
            app.pos_9_8.ImageSource = 'piece_0.png';
            app.pos_9_9 = uiimage(app.UIFigure);
            app.pos_9_9.ImageSource = 'piece_0.png';
            app.pos_9_10 = uiimage(app.UIFigure);
            app.pos_9_10.ImageSource = 'piece_0.png';
            app.DisplayTime = uilabel(app.UIFigure);
            app.DisplayTime.Text = '';
            app.choiceBox = uiimage(app.UIFigure);
            app.choiceBox.Visible = 'off';
            app.choiceBox.ImageSource = 'choiceBox.png';
            app.fastforward = uiimage(app.UIFigure);
            app.fastforward.ImageClickedFcn = createCallbackFcn(app, @fastforwardImageClicked, true);
            app.fastforward.ImageSource = 'end.png';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {' '};
            app.exit = uibutton(app.UIFigure, 'push');
            app.exit.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exit.Tag = 'confirm';
            app.exit.Visible = 'off';
            app.exit.Text = '返回';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LogPlayer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end