classdef StartPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Cross            matlab.ui.control.Image
        ip_4             matlab.ui.control.NumericEditField
        ip_3             matlab.ui.control.NumericEditField
        ip_2             matlab.ui.control.NumericEditField
        ip_1             matlab.ui.control.NumericEditField
        Choice2          matlab.ui.control.Button
        Choice1          matlab.ui.control.Button
        Message          matlab.ui.control.Label
        AlphaBackground  matlab.ui.control.Image
        OnlineButton     matlab.ui.control.Button
        HumanButton      matlab.ui.control.Button
        PlayRecButton    matlab.ui.control.Button
        Title            matlab.ui.control.Label
        Background       matlab.ui.control.Image
    end

    
    properties (Access = private)
        ViewModel           StartViewModel
    end
    
    methods (Access = public)
        
        function noConnection(app)
            app.AlphaBackground.Visible = "on";
            app.Cross.Visible = "on";
            app.ip_1.Visible = "off";
            app.ip_2.Visible = "off";
            app.ip_3.Visible = "off";
            app.ip_4.Visible = "off";
            app.Choice1.Visible = "off";
            app.Choice2.Visible = "off";
            app.Message.Text = "连接超时";
            app.Message.Visible = "on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, VM)
            app.ViewModel = VM;
        end

        % Button pushed function: HumanButton
        function HumanButtonPushed(app, event)
            app.ViewModel.HumanHuman();
            close(app.UIFigure);
        end

        % Button pushed function: PlayRecButton
        function PlayRecButtonPushed(app, event)
            [file,path] = uigetfile('*.rec');
            if isequal(file,0)
               return;
            else
               app.ViewModel.PlayRec(fullfile(path,file));
               close(app.UIFigure);
            end
            
        end

        % Button pushed function: OnlineButton
        function OnlineButtonPushed(app, event)
            app.AlphaBackground.Visible = "on";
            app.Cross.Visible = "on";
            app.Message.Text = "线上游戏";
            app.Message.Visible = "on";
            app.Choice1.Text = "创建";
            app.Choice2.Text = "加入";
            app.Choice1.Visible = "on";
            app.Choice2.Visible = "on";
        end

        % Button pushed function: Choice1
        function Choice1ButtonPushed(app, event)
            switch app.Choice1.Text
                case "创建"
                    app.Message.Text = "等待连接";
                    app.Choice1.Visible = "off";
                    app.Choice2.Visible = "off";
                    app.Cross.Visible = "off";
                    pause(0);
                    app.ViewModel.Online("Host", "0.0.0.0");
                case "连接"               % Trying to connect to others
                    app.Cross.Visible = "off";
                    app.Message.Text = "请稍等";
                    app.Message.Visible = "on";
                    app.ip_1.Visible = "off";
                    app.ip_2.Visible = "off";
                    app.ip_3.Visible = "off";
                    app.ip_4.Visible = "off";
                    app.Choice1.Visible = "off";
                    app.Choice2.Visible = "off";
                    pause(0);
                    app.ViewModel.Online("Client", num2str(app.ip_1.Value) + "." + num2str(app.ip_2.Value) + "." + num2str(app.ip_3.Value) + "." + num2str(app.ip_4.Value));
            end
        end

        % Button pushed function: Choice2
        function Choice2ButtonPushed(app, event)
            switch app.Choice2.Text
                case "加入"
                    app.Message.Visible = "off";
                    app.ip_1.Visible = "on";
                    app.ip_2.Visible = "on";
                    app.ip_3.Visible = "on";
                    app.ip_4.Visible = "on";
                    app.Choice1.Text = "连接";
                    app.Choice2.Text = "取消";
                    app.Cross.Visible = "off";
                case "取消"
                    app.Cross.Visible = "on";
                    app.Message.Text = "线上游戏";
                    app.Message.Visible = "on";
                    app.Choice1.Text = "创建";
                    app.Choice2.Text = "加入";
                    app.Choice1.Visible = "on";
                    app.Choice2.Visible = "on";
                    app.ip_1.Visible = "off";
                    app.ip_2.Visible = "off";
                    app.ip_3.Visible = "off";
                    app.ip_4.Visible = "off";
            end
        end

        % Image clicked function: Cross
        function CrossImageClicked(app, event)
            app.Cross.Visible = "off";
            app.ip_1.Visible = "off";
            app.ip_2.Visible = "off";
            app.ip_3.Visible = "off";
            app.ip_4.Visible = "off";
            app.Choice1.Visible = "off";
            app.Choice2.Visible = "off";
            app.Message.Visible = "off";
            app.AlphaBackground.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Background = uiimage(app.UIFigure);
            app.Background.ImageSource = 'StartPage.png';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = '中国象棋';
            app.PlayRecButton = uibutton(app.UIFigure, 'push');
            app.PlayRecButton.ButtonPushedFcn = createCallbackFcn(app, @PlayRecButtonPushed, true);
            app.PlayRecButton.Text = '观看回放';
            app.HumanButton = uibutton(app.UIFigure, 'push');
            app.HumanButton.ButtonPushedFcn = createCallbackFcn(app, @HumanButtonPushed, true);
            app.HumanButton.Text = '双人对弈';
            app.OnlineButton = uibutton(app.UIFigure, 'push');
            app.OnlineButton.ButtonPushedFcn = createCallbackFcn(app, @OnlineButtonPushed, true);
            app.OnlineButton.Text = '线上游戏';
            app.AlphaBackground = uiimage(app.UIFigure);
            app.AlphaBackground.Tag = 'confirm';
            app.AlphaBackground.Visible = 'off';
            app.AlphaBackground.ImageSource = 'Confirm.png';
            app.Message = uilabel(app.UIFigure);
            app.Message.Tag = 'confirm';
            app.Message.Visible = 'off';
            app.Message.Text = '线上游戏';
            app.Choice1 = uibutton(app.UIFigure, 'push');
            app.Choice1.ButtonPushedFcn = createCallbackFcn(app, @Choice1ButtonPushed, true);
            app.Choice1.Tag = 'confirm';
            app.Choice1.Visible = 'off';
            app.Choice1.Text = '创建';
            app.Choice2 = uibutton(app.UIFigure, 'push');
            app.Choice2.ButtonPushedFcn = createCallbackFcn(app, @Choice2ButtonPushed, true);
            app.Choice2.Tag = 'confirm';
            app.Choice2.Visible = 'off';
            app.Choice2.Text = '加入';
            app.ip_1 = uieditfield(app.UIFigure, 'numeric');
            app.ip_1.Limits = [0 255];
            app.ip_1.ValueDisplayFormat = '%.0f';
            app.ip_1.Visible = 'off';
            app.ip_1.Value = 192;
            app.ip_2 = uieditfield(app.UIFigure, 'numeric');
            app.ip_2.Limits = [0 255];
            app.ip_2.ValueDisplayFormat = '%.0f';
            app.ip_2.Visible = 'off';
            app.ip_2.Value = 168;
            app.ip_3 = uieditfield(app.UIFigure, 'numeric');
            app.ip_3.Limits = [0 255];
            app.ip_3.ValueDisplayFormat = '%.0f';
            app.ip_3.Visible = 'off';
            app.ip_4 = uieditfield(app.UIFigure, 'numeric');
            app.ip_4.Limits = [0 255];
            app.ip_4.ValueDisplayFormat = '%.0f';
            app.ip_4.Visible = 'off';
            app.ip_4.Value = 1;
            app.Cross = uiimage(app.UIFigure);
            app.Cross.ImageClickedFcn = createCallbackFcn(app, @CrossImageClicked, true);
            app.Cross.Visible = 'off';
            app.Cross.ImageSource = 'Cross.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StartPage(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end