classdef patient_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TextArea                      matlab.ui.control.TextArea
        InjectBolusButton             matlab.ui.control.Button
        CallPhysicianButton           matlab.ui.control.Button
        InformationofPatientTextArea  matlab.ui.control.TextArea
        InformationofPatientTextAreaLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        controller              % controller.m
        Name='patient_1';       % patient Name
        ID='1';                 % patient ID
        error3;
        error4;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % print patient information
            name_id=sprintf('Name:%s\nID:%s',app.Name,app.ID);
            app.InformationofPatientTextArea.Value=name_id;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(timerfind)
                stop(timerfind);
                delete(timerfind);
            end
            delete(app);
        end

        % Button pushed function: CallPhysicianButton
        function CallPhysicianButtonPushed(app, event)
            % patient call physicain, the 'Patirnt Calling'lamp in physician_UI will be red
            app.controller.physician_UI.patientCall;
        end

        % Button pushed function: InjectBolusButton
        function InjectBolusButtonPushed(app, event)
            % inject a Bouls (disabled when injector is off)
            if (app.controller.state==1)  % injector on
                if ((app.controller.Bolus<=app.controller.Left_amount) && (app.controller.exceed_limit_day==0) && (app.controller.exceed_limit_hour==0) && (app.controller.hour_injected+app.controller.Bolus<app.controller.limit_per_hour-app.controller.Baseline*0.1) && (app.controller.day_injected+app.controller.Bolus<app.controller.limit_per_day-app.controller.Baseline*0.1))
                    app.controller.Left_amount=app.controller.Left_amount-app.controller.Bolus;
                    app.controller.hour_injected=app.controller.hour_injected+app.controller.Bolus;
                    app.controller.day_injected=app.controller.day_injected+app.controller.Bolus;
                    app.controller.hour_vec(app.controller.hour_vec_ptr-1)=app.controller.hour_vec(app.controller.hour_vec_ptr-1)+app.controller.Bolus;
                    app.controller.day_vec(app.controller.day_vec_ptr-1)=app.controller.day_vec(app.controller.day_vec_ptr-1)+app.controller.Bolus;
                    left=sprintf('%.2f',app.controller.Left_amount);
                    app.controller.physician_UI.Left_amountTextArea.Value=left;
                elseif (app.controller.Bolus>app.controller.Left_amount)  % Error
                    app.error3 = msgbox('Error code 3,Insufficient left amount', 'Error','error');
                else    % Error
                    app.error4 = msgbox('Error code 4,hour injection limit or day injection limit will or has already exceeded.', 'Error','error');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InformationofPatientTextAreaLabel = uilabel(app.UIFigure);
            app.InformationofPatientTextAreaLabel.Text = 'Information of Patient';
            app.InformationofPatientTextArea = uitextarea(app.UIFigure);
            app.InformationofPatientTextArea.Editable = 'off';
            app.CallPhysicianButton = uibutton(app.UIFigure, 'push');
            app.CallPhysicianButton.ButtonPushedFcn = createCallbackFcn(app, @CallPhysicianButtonPushed, true);
            app.CallPhysicianButton.Text = 'Call Physician';
            app.InjectBolusButton = uibutton(app.UIFigure, 'push');
            app.InjectBolusButton.ButtonPushedFcn = createCallbackFcn(app, @InjectBolusButtonPushed, true);
            app.InjectBolusButton.Enable = 'off';
            app.InjectBolusButton.Text = 'Inject Bolus';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Patient UI for Pain Killer Injection system'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patient_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end