classdef physician_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TextArea                        matlab.ui.control.TextArea
        FunctionofTodayButton           matlab.ui.control.Button
        FunctionofthishourButton        matlab.ui.control.Button
        CurrentTimeMinuteGauge          matlab.ui.control.Gauge
        CurrentTimeMinuteGaugeLabel     matlab.ui.control.Label
        CurrentTimeHourGauge            matlab.ui.control.Gauge
        CurrentTimeHourGaugeLabel       matlab.ui.control.Label
        injectedthishourTextArea        matlab.ui.control.TextArea
        injectedthishourTextAreaLabel   matlab.ui.control.Label
        injectedtodayTextArea           matlab.ui.control.TextArea
        injectedtodayTextAreaLabel      matlab.ui.control.Label
        CurrentInjectionAmountLabel     matlab.ui.control.Label
        ResolvePatientCallButton        matlab.ui.control.Button
        PatientCallingLamp              matlab.ui.control.Lamp
        PatientCallingLampLabel         matlab.ui.control.Label
        FillInjectorButton              matlab.ui.control.Button
        InformationofPhysicianTextArea  matlab.ui.control.TextArea
        InformationofPhysicianTextAreaLabel  matlab.ui.control.Label
        SetBolusEditField               matlab.ui.control.NumericEditField
        SetBolusEditFieldLabel          matlab.ui.control.Label
        SetBaselineEditField            matlab.ui.control.NumericEditField
        SetBaselineEditFieldLabel       matlab.ui.control.Label
        Left_amountTextArea             matlab.ui.control.TextArea
        Left_amountTextAreaLabel        matlab.ui.control.Label
        BaselineTextArea                matlab.ui.control.TextArea
        BaselineTextAreaLabel           matlab.ui.control.Label
        PowerLamp                       matlab.ui.control.Lamp
        PowerLampLabel                  matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        SwitchLabel                     matlab.ui.control.Label
    end

    
    properties (Access = public)
        controller              % controller.m
        Name='physician_1';     % physician Name
        ID='1';                 % physician ID
        first_on=0;             % state whether injector has on at least once,1 yes 0 no 
        old_Baseline=0.01;
        old_Bolus=0.2;
        error1;
        error2;
        hour_figure;
        day_figure;
    end
    
    methods (Access = public)
        %  print Baseline and Bolus,change color of power lamp
        function changeState(app,state,Baseline,Left_amount)
            switch state
                case 0
                    app.PowerLamp.Color=[0.5,0.5,0.5];
                case 1
                    app.PowerLamp.Color=[0,1,0];
            end
            bas=sprintf('%.2f',Baseline);
            app.BaselineTextArea.Value=bas;
            left=sprintf('%.2f',Left_amount);
            app.Left_amountTextArea.Value=left;
        end
        
        % indicate a patinet call, change color of patient call lamp to red
        function patientCall(app)
            app.PatientCallingLamp.Color=[1,0,0];
        end
        
        % reslove a patinet call, change color of patient call lamp to grey 
        function resolveCall(app)
            app.PatientCallingLamp.Color=[0.5,0.5,0.5];
        end
        
        % print hour,minute,inject this day,inject this hour
        function showTime(app,Hour,Minute,Day_injected,Hour_injected)
            app.CurrentTimeHourGauge.Value=Hour+Minute/60;
            app.CurrentTimeMinuteGauge.Value=Minute;
            day_inj=sprintf('%.2f',Day_injected);
            app.injectedtodayTextArea.Value=day_inj;
            hou_inj=sprintf('%.2f',Hour_injected);
            app.injectedthishourTextArea.Value=hou_inj;
            %draw_func(app);
        end
        
        function func_hour(app)
            app.hour_figure = figure;
            x=0.1:0.1:1440;
            y=app.controller.func_hour_vec;
            plot(x,y);
            xlabel('time/min');
            ylabel('amount/ml');
            title('Inject amount in the recent hour as the function of time')
            xlim([0,app.controller.minute_elapsed]);
            ylim([0,1.05]);
        end
        function func_day(app)
            app.day_figure = figure;
            x=0.1:0.1:1440;
            y=app.controller.func_day_vec;
            plot(x,y);
            xlabel('time/min');
            ylabel('amount/ml');
            title('Inject amount in the recent day as the function of time')
            xlim([0,app.controller.minute_elapsed]);
            ylim([0,3.05]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % print information of physician
            name_id=sprintf('Name:%s\nID:%s',app.Name,app.ID);
            app.InformationofPhysicianTextArea.Value=name_id;        
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            % switch of injector 
            value = app.Switch.Value;
            if(strcmp('Off',value)==1)
                stop(app.controller.t);
                app.controller.state=0;
                app.PowerLamp.Color=[0.5,0.5,0.5];  
                app.FunctionofTodayButton.Enable="off";
                app.FunctionofthishourButton.Enable="off";
                app.SetBaselineEditField.Editable="off";
                app.SetBaselineEditField.Enable="off";
                app.SetBolusEditField.Editable="off";
                app.SetBolusEditField.Enable="off";
                app.controller.patient_UI.InjectBolusButton.Enable="off";
            elseif(strcmp('On',value)==1)
                app.FunctionofTodayButton.Enable="on";
                app.FunctionofthishourButton.Enable="on";
                app.SetBaselineEditField.Enable="on";
                app.SetBaselineEditField.Editable="on";
                app.SetBolusEditField.Enable="on";
                app.SetBolusEditField.Editable="on";
                app.controller.patient_UI.InjectBolusButton.Enable="on";
                if (app.controller.Left_amount>0) % not empty
                    start(app.controller.t);
                end
                if app.first_on==0 % first time injector is on,start timer t1
                    start(app.controller.t1);
                end
                app.controller.state=1;
                app.first_on=1;
                app.PowerLamp.Color=[0,1,0];
            end
        end

        % Value changed function: SetBolusEditField
        function SetBolusEditFieldValueChanged(app, event)
            % set value of Bolus
            value = app.SetBolusEditField.Value;

            if (value<0.2 || value>0.5)   % Error( non-numberical input is handled by matlab already)
                 app.error1 = msgbox('Error code 1,Bolus should between 0.2 and 0.5', 'Error','error');
                 app.SetBolusEditField.Value=app.old_Bolus;
            else    % valid input
                value=value*100;
                value=round(value);
                value=value/100;
                app.controller.Bolus=value;  
                app.old_Bolus=value;
                app.SetBolusEditField.Value=app.controller.Bolus;
            end
        end

        % Value changed function: SetBaselineEditField
        function SetBaselineEditFieldValueChanged(app, event)
            % set value of Baseline
            value = app.SetBaselineEditField.Value;
            if (value<0.01 || value>0.1)  % Error( non-numberical input is handled by matlab already)
                app.error2 = msgbox('Error code 2,Baseline should between 0.01 and 0.1', 'Error','error');
                app.SetBaselineEditField.Value=app.old_Baseline;
            else    % valid input          
                value=value*100;
                value=round(value);
                value=value/100;
                app.controller.Baseline=value;
                app.old_Baseline=value;
                app.SetBaselineEditField.Value=app.controller.Baseline;
            end  
        end

        % Callback function
        function InjectBolusButtonPushed(app, event)
         
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(timerfind)
                stop(timerfind);
                delete(timerfind);
            end
            delete(app);
        end

        % Button pushed function: FillInjectorButton
        function FillInjectorButtonPushed(app, event)
            % fill the injector
            if app.controller.Left_amount<=0    % empty
                app.controller.Left_amount=app.controller.Amount;
                start(app.controller.t);
            else    % non-empty
                app.controller.Left_amount=app.controller.Amount;
            end
            left=sprintf('%.2f',app.controller.Left_amount);
            app.Left_amountTextArea.Value=left;
        end

        % Button pushed function: ResolvePatientCallButton
        function ResolvePatientCallButtonPushed(app, event)
            % reslove call from patient
            app.resolveCall;
        end

        % Button pushed function: FunctionofthishourButton
        function FunctionofthishourButtonPushed(app, event)
        func_hour(app);
        end

        % Button pushed function: FunctionofTodayButton
        function FunctionofTodayButtonPushed(app, event)
        func_day(app);  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.PowerLampLabel = uilabel(app.UIFigure);
            app.PowerLampLabel.Text = 'Power';
            app.PowerLamp = uilamp(app.UIFigure);
            app.BaselineTextAreaLabel = uilabel(app.UIFigure);
            app.BaselineTextAreaLabel.Text = 'Baseline';
            app.BaselineTextArea = uitextarea(app.UIFigure);
            app.BaselineTextArea.Editable = 'off';
            app.Left_amountTextAreaLabel = uilabel(app.UIFigure);
            app.Left_amountTextAreaLabel.Text = 'Left_amount';
            app.Left_amountTextArea = uitextarea(app.UIFigure);
            app.Left_amountTextArea.Editable = 'off';
            app.SetBaselineEditFieldLabel = uilabel(app.UIFigure);
            app.SetBaselineEditFieldLabel.Text = 'Set Baseline';
            app.SetBaselineEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetBaselineEditField.ValueDisplayFormat = '%.2f';
            app.SetBaselineEditField.ValueChangedFcn = createCallbackFcn(app, @SetBaselineEditFieldValueChanged, true);
            app.SetBaselineEditField.Editable = 'off';
            app.SetBaselineEditField.Enable = 'off';
            app.SetBaselineEditField.Value = 0.01;
            app.SetBolusEditFieldLabel = uilabel(app.UIFigure);
            app.SetBolusEditFieldLabel.Text = 'Set  Bolus';
            app.SetBolusEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetBolusEditField.ValueDisplayFormat = '%.2f';
            app.SetBolusEditField.ValueChangedFcn = createCallbackFcn(app, @SetBolusEditFieldValueChanged, true);
            app.SetBolusEditField.Editable = 'off';
            app.SetBolusEditField.Enable = 'off';
            app.SetBolusEditField.Value = 0.2;
            app.InformationofPhysicianTextAreaLabel = uilabel(app.UIFigure);
            app.InformationofPhysicianTextAreaLabel.Text = 'Information of Physician';
            app.InformationofPhysicianTextArea = uitextarea(app.UIFigure);
            app.InformationofPhysicianTextArea.Editable = 'off';
            app.FillInjectorButton = uibutton(app.UIFigure, 'push');
            app.FillInjectorButton.ButtonPushedFcn = createCallbackFcn(app, @FillInjectorButtonPushed, true);
            app.FillInjectorButton.Text = 'Fill Injector';
            app.PatientCallingLampLabel = uilabel(app.UIFigure);
            app.PatientCallingLampLabel.Text = 'Patient Calling';
            app.PatientCallingLamp = uilamp(app.UIFigure);
            app.ResolvePatientCallButton = uibutton(app.UIFigure, 'push');
            app.ResolvePatientCallButton.ButtonPushedFcn = createCallbackFcn(app, @ResolvePatientCallButtonPushed, true);
            app.ResolvePatientCallButton.Text = 'Resolve Patient Call';
            app.CurrentInjectionAmountLabel = uilabel(app.UIFigure);
            app.CurrentInjectionAmountLabel.Text = 'Current Injection Amount';
            app.injectedtodayTextAreaLabel = uilabel(app.UIFigure);
            app.injectedtodayTextAreaLabel.Text = 'injected today';
            app.injectedtodayTextArea = uitextarea(app.UIFigure);
            app.injectedtodayTextArea.Editable = 'off';
            app.injectedthishourTextAreaLabel = uilabel(app.UIFigure);
            app.injectedthishourTextAreaLabel.Text = 'injected this hour';
            app.injectedthishourTextArea = uitextarea(app.UIFigure);
            app.injectedthishourTextArea.Editable = 'off';
            app.CurrentTimeHourGaugeLabel = uilabel(app.UIFigure);
            app.CurrentTimeHourGaugeLabel.Text = 'Current Time Hour';
            app.CurrentTimeHourGauge = uigauge(app.UIFigure, 'circular');
            app.CurrentTimeHourGauge.Limits = [0 24];
            app.CurrentTimeMinuteGaugeLabel = uilabel(app.UIFigure);
            app.CurrentTimeMinuteGaugeLabel.Text = 'Current Time Minute';
            app.CurrentTimeMinuteGauge = uigauge(app.UIFigure, 'circular');
            app.CurrentTimeMinuteGauge.Limits = [0 60];
            app.FunctionofthishourButton = uibutton(app.UIFigure, 'push');
            app.FunctionofthishourButton.ButtonPushedFcn = createCallbackFcn(app, @FunctionofthishourButtonPushed, true);
            app.FunctionofthishourButton.Enable = 'off';
            app.FunctionofthishourButton.Text = 'Function of this hour';
            app.FunctionofTodayButton = uibutton(app.UIFigure, 'push');
            app.FunctionofTodayButton.ButtonPushedFcn = createCallbackFcn(app, @FunctionofTodayButtonPushed, true);
            app.FunctionofTodayButton.Enable = 'off';
            app.FunctionofTodayButton.Text = 'Function of Today';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Physician UI for Pain Killer Injection system'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = physician_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end